classdef ConeFinderApp_v2020b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeFinderUIFigure              matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewrecognitionMenu              matlab.ui.container.Menu
        OpenrecognitionMenu             matlab.ui.container.Menu
        SaverecognitionMenu             matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        VoronoitypeButtonGroup          matlab.ui.container.ButtonGroup
        VoronoiDiagramCheckBox          matlab.ui.control.CheckBox
        NumberofNeighborsButton         matlab.ui.control.RadioButton
        ConeAreaButton                  matlab.ui.control.RadioButton
        EmptyButton                     matlab.ui.control.RadioButton
        InfoLabel                       matlab.ui.control.Label
        ImproveCNNResultsPanel          matlab.ui.container.Panel
        DelDoublesButton                matlab.ui.control.Button
        DelOutsideImageButton           matlab.ui.control.Button
        VoronoiPanel                    matlab.ui.container.Panel
        ShowConeLocationsCheckBox       matlab.ui.control.CheckBox
        EditConeLocationsPanel          matlab.ui.container.Panel
        HelpTextArea                    matlab.ui.control.TextArea
        HelpTextAreaLabel               matlab.ui.control.Label
        AddConelocsOutsideBoxCNNButton  matlab.ui.control.Button
        AddConelocsbyfastpeakfindButton  matlab.ui.control.Button
        DeletedSelectedConesDelButton   matlab.ui.control.Button
        ChangeBoxSizeButton             matlab.ui.control.Button
        MarksPropertiesPanel            matlab.ui.container.Panel
        ShowMarkedLocationsTCheckBox    matlab.ui.control.CheckBox
        HighlightAutodetectedCones0CheckBox  matlab.ui.control.CheckBox
        ShowMarksOptionsButtonGroup     matlab.ui.container.ButtonGroup
        Autodetect1Button               matlab.ui.control.RadioButton
        Userdetect2Button               matlab.ui.control.RadioButton
        AutoUser3Button                 matlab.ui.control.RadioButton
        ConeMarksTypeButtonGroup        matlab.ui.container.ButtonGroup
        CrossXButton                    matlab.ui.control.RadioButton
        CircleCButton                   matlab.ui.control.RadioButton
        DotZButton                      matlab.ui.control.RadioButton
        ImagePropertiesPanel            matlab.ui.container.Panel
        ShowImageICheckBox              matlab.ui.control.CheckBox
        LaplacianofGaussianfilterCheckBox  matlab.ui.control.CheckBox
        LogfilterLCheckBox              matlab.ui.control.CheckBox
        GridPanel                       matlab.ui.container.Panel
        ShowGridNumbersECheckBox        matlab.ui.control.CheckBox
        ShowGridQCheckBox               matlab.ui.control.CheckBox
        BrightnessPanel                 matlab.ui.container.Panel
        ResetBrightnessRButton          matlab.ui.control.Button
        BrightnessDBSlider              matlab.ui.control.Slider
        BrightnessDBSliderLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
         
        % Zoom configuration options
        Magnify = 1.1;
        XMagnify = 1.0;
        YMagnify = 1.0;
        ChangeMagnify = 1.1;
        IncreaseChange = 1.1;
        MinValue = 1.1;
        MaxZoomScrollCount = 30;
        ImgWidth = 0;
        ImgHeight = 0;
        ZoomScrollCount = 0;
        XLim=[];
        YLim=[];
        
        % Shows whether app is Busy
        IsBusy = 0;
    end
    
    properties (Access = public)
        % Files
        Fname;
        Pname;
        
        % Image
        Image;
        Ttl;
        
        % Voronoi
        VColorMap;
        VoronoiPlot = 1;
        PrintConesOnVoronoi = 0;
        UseCorrectedPointsInVoronoi = 0;
        VoronoiType = 'Empty';
        % For livetime update
        VoronoiDelanayTriang;
        VoronoiAplhaShape;
        VoronoiVerticies;
        
        VoronoiAreaColors;
        VoronoiMaxAreaPixel;
        VoronoiConeAreaColorScale;
        VoronoiAreaColorIndexes;
        VoronoiNNColors;
        VoronoiNNColorScale;
        VoronoiMinNN = 4;
        VoronoiMaxNN = 9;


        % Cone Locations
        Conelocs = [];
        BoxPosition;
        TotalConeCount = 0;
        ConeCount = 0;
        Minlimx = 0;
        Minlimy = 0;
        Maxlimx = 0;
        Maxlimy = 0;
        
        % Mark properties
        MarkerSize = 2;
        MarkType = '.y';
        ShowConesByDetectType = 0;
        Color = [1 1 0];
        Marker = '.';
        
        
        % Image properties
        Brightness = 1.0;
        
        % Handler of figure window
        FigureHandler;
        ImageShowHandler;
        ScrollPanelHandler;
        MagBoxHandler;
        ScrollPanelApi;
        MiniMapHandler;
        ConeUserPlotHandler;
        ConeAutoPlotHandler;
        VoronoiPatchHandler;
        GridHorLinesHandler;
        GridVertLinesHandler;
        GridTextHandler;
        IsClosing = 0;
        
        % Edit Mode
        EditCorrectedCones = 0;
    end
    
    methods (Access = public)
        
        % Marks cone on image
        function MarkCone(app, x, y)
            % CNN generates more exact cone
            % locations anyway
            app.Conelocs(app.TotalConeCount+1,1) = x;
            app.Conelocs(app.TotalConeCount+1,2) = y;
            if x < app.Minlimx || x > app.Maxlimx || y < app.Minlimy || y > app.Maxlimy
                app.Conelocs(app.TotalConeCount+1,3) = 3;
            else
                app.Conelocs(app.TotalConeCount+1,3) = 2;
            end
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateImage(app);
            UpdateLegacy(app);
        end
        
        % Removes Mark from image
        function RemoveMark(app, x, y)
            % Clear marked cone nearest to click location
            if (size(app.Conelocs,1)>0)
                currentloc = [x y];
                % compute Euclidean distances:
                distances = sqrt(sum(bsxfun(@minus, app.Conelocs(:,1:2), currentloc).^2,2));
                                
                % find the smallest distance and delete that index in app.Conelocs:
                app.Conelocs(distances==min(distances),:)=[];
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
                app.TotalConeCount = size(app.Conelocs,1);
            
                % After deletion, plot current set of cones
                UpdateImage(app);
                UpdateLegacy(app);
            end
        end
        
        % Saves current cone locations to a .mat file
        function SaveCurrentLocations(app)
            [pstr, nm, ext] = fileparts([app.Pname app.Fname]);
            
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                name1 = [pstr filesep nm '_annotated.png'];
                name2 = [pstr filesep nm '_annotated.mat'];
            elseif strcmp(ext,'.mat')==1
                name1 = [pstr filesep nm '.png'];
                name2 = [app.Pname app.Fname];
            end
            
            if ~app.IsClosing
                print(app.FigureHandler, name1, '-dpng');
            end
            
            % names of variables must be the same to names in 'save' function
            conecount = app.ConeCount;
            I = app.Image;
            conelocs = app.Conelocs;
            boxposition = app.BoxPosition;
            save(name2, 'conecount', 'I', 'conelocs', 'boxposition');
            
            app.Ttl.BackgroundColor = [0 1 0];
        end
        
        % Updates Image ------------------------------------------------------------
        function UpdateImage(app)
            % Clean all objects from Axes, except Image
%             delete(findobj(gca, '-not', 'Type', 'Image', '-and', '-not', 'Type', 'Axes'));
            
            PrintImage(app, app.Image);
                        
            PrintVoronoiByType(app, app.VoronoiType, app.Conelocs);
                        
            % Print cone locations
            PlotConelocs(app, app.BoxPosition, app.Conelocs);
            
            uistack(app.ConeUserPlotHandler, 'top');
            uistack(app.ConeAutoPlotHandler, 'top');
            
            % Print Grid
            PrintGrid(app, app.Image);
            
            
            CreateScrollMinimap(app);
        end
        
        % Prints image
        function PrintImage(app, image)
            blank = zeros(size(image));
            
            if ~app.ShowImageICheckBox.Value
                % Print blank image
                if isempty(app.ImageShowHandler)
                    cla; app.ImageShowHandler = imshow(blank); hold on;
                else
                    app.ImageShowHandler.CData = blank;
                end
            else
                % Get image with given brightness
                J = imadjust(image,[],[],app.Brightness);
                
                if app.LogfilterLCheckBox.Value
                    % Get log image
                    J = GetLogImage(app, J);
                end
                
                if app.LaplacianofGaussianfilterCheckBox.Value
                    % Get Laplacian of Gaussian filter
                    J = GetLapOfGausImage(app, J);
                end
                
                % Print image
                if isempty(app.ImageShowHandler)
                    cla; app.ImageShowHandler = imshow(J); hold on;
                else
                    app.ImageShowHandler.CData = J;
                end
            end
        end
        
        % Gets log10 image
        function image = GetLogImage(~, Image)
            R = max(max(Image));
            c = 255 / (log10(double(1 + R)));
            Image = c * log10(double(Image + 1));
            image = uint8(Image);
        end
        
        % Gets Laplacian of Gaussian image
        function image = GetLapOfGausImage(~, Image)
            filter = fspecial('log',5, 0.35);
            image = imfilter(Image, filter);
        end
        
%         % Prints cone locations
%         function PlotConelocs(app, boxPosition, conelocs)
%             rectangle('Position', boxPosition,'EdgeColor',[1 0 1]);
%             
%             if app.ShowMarkedLocationsTCheckBox.Value
%                 switch app.ShowConesByDetectType
%                     case 1
%                         usercones = conelocs(conelocs(:,3)==2,1:2);
%                         app.ConeUserPlotHandler = plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
%                         
%                     case 2
%                         autocones = conelocs(conelocs(:,3)==1,1:2);
%                         app.ConeAutoPlotHandler = plot(autocones(:,1),autocones(:,2),'.m');
%                         
%                     otherwise
%                         if ~app.HighlightAutodetectedCones0CheckBox.Value
%                             app.ConeAutoPlotHandler = plot(conelocs(conelocs(:,3)~=0,1), conelocs(conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize);
%                         else
%                             usercones = conelocs(conelocs(:,3)==2,1:2);
%                             autocones = conelocs(conelocs(:,3)==1,1:2);
%                             app.ConeAutoPlotHandler = plot(autocones(:,1),autocones(:,2),'.m');
%                             app.ConeUserPlotHandler = plot(usercones(:,1),usercones(:,2), app.MarkType, 'MarkerSize', app.MarkerSize);
%                         end
%                 end
%             end
%         end
        
        % Prints cone locations
        function PlotConelocs(app, boxPosition, conelocs)
%             rectangle('Position', boxPosition,'EdgeColor',[1 0 1]);
            
            if app.ShowMarkedLocationsTCheckBox.Value
                markerSize = app.MarkerSize;
                usercones = conelocs(conelocs(:,3)==2,1:2);
                autocones = conelocs(conelocs(:,3)==1,1:2);
                switch app.ShowConesByDetectType
                    case 1
                        userMarker = app.Marker;
                        userColor = app.Color;
                        userVisability = 'on';
                        autoMarker = '.';
                        % magenta
                        autoColor = [1 0 1];
                        autoVisability = 'off';
                        
                    case 2
                        userMarker = app.Marker;
                        userColor = app.Color;
                        userVisability = 'off';
                        autoMarker = '.';
                        % magenta
                        autoColor = [1 0 1];
                        autoVisability = 'on';
                    otherwise
                        userVisability = 'on';
                        autoVisability = 'on';
                        if ~app.HighlightAutodetectedCones0CheckBox.Value
                            userMarker = app.Marker;
                            userColor = app.Color;
                            autoMarker = app.Marker;
                            autoColor = app.Color;
                        else
                            userMarker = app.Marker;
                            userColor = app.Color;
                            autoMarker = '.';
                            % magenta
                            autoColor = [1 0 1];
                        end
                end
                    
                % check where plots are created
                if isempty(app.ConeUserPlotHandler) || ~isvalid(app.ConeUserPlotHandler)
                    app.ConeUserPlotHandler = plot(1, 1, '.');
                end
                if isempty(app.ConeAutoPlotHandler) || ~isvalid(app.ConeAutoPlotHandler)
                    app.ConeAutoPlotHandler = plot(1, 1, '.');
                end
                
                % set plots porperties
                set(app.ConeUserPlotHandler, ...
                    'XData', usercones(:,1), 'YData', usercones(:,2), ...
                    'MarkerSize', markerSize, 'Marker', userMarker, ...
                    'Color', userColor, 'Visible', userVisability);
                set(app.ConeAutoPlotHandler, ...
                    'XData', autocones(:,1), 'YData', autocones(:,2), ...
                    'MarkerSize', markerSize, 'Marker', autoMarker, ...
                    'Color', autoColor, 'Visible', autoVisability);
            else  
                % check where plots are created
                if ~isempty(app.ConeUserPlotHandler) && isvalid(app.ConeUserPlotHandler)
                    set(app.ConeUserPlotHandler, 'Visible', 'off');
                end
                if ~isempty(app.ConeAutoPlotHandler) && isvalid(app.ConeAutoPlotHandler)
                    set(app.ConeAutoPlotHandler, 'Visible', 'off');
                end
            end
        
        end
        
        % Prints Grid
        function PrintGrid(app, image)
            if ~isempty(app.GridVertLinesHandler) & isvalid(app.GridVertLinesHandler)
                delete(app.GridVertLinesHandler);
            end
            if ~isempty(app.GridHorLinesHandler) & isvalid(app.GridHorLinesHandler)
                delete(app.GridHorLinesHandler);
            end
            if ~isempty(app.GridTextHandler) & isvalid(app.GridTextHandler)
                delete(app.GridTextHandler);
            end
            
            
            if app.ShowGridQCheckBox.Value
                % Get sizes
                [originalSizeY, originalSizeX, ~] = size(image);
                
                % Calculation like during CNN cone recogtion
                newSize = 150;
                
                % calculate minimum numer of cutouts to produce
                cutoutsINrowX = ceil(originalSizeX/newSize); 
                cutoutsINrowY = ceil(originalSizeY/newSize);
    
                offsetX = floor(cutoutsINrowX*newSize-originalSizeX)/(cutoutsINrowX-1);
    
                while offsetX < 20
                    cutoutsINrowX = cutoutsINrowX+1;
                    % offset which will be applied for a fix number of cutouts in a row/column
                    offsetX = round((cutoutsINrowX*newSize-originalSizeX)/(cutoutsINrowX-1));
                end
                
                offsetY = floor(cutoutsINrowY*newSize-originalSizeY)/(cutoutsINrowY-1);
    
                while offsetY < 20
                    cutoutsINrowY = cutoutsINrowY+1;
                    % offset which will be applied for a fix number of cutouts in a row/column
                    offsetY = round((cutoutsINrowY*newSize-originalSizeY)/(cutoutsINrowY-1));
                end
    
                cutoutsINrowX = cutoutsINrowX + 1;
                cutoutsINrowY = cutoutsINrowY + 1;
                stepX = newSize-1-offsetX;
                linesCols = [1 newSize-offsetX : stepX : ((newSize-1)*cutoutsINrowX-offsetX*cutoutsINrowX)];
    
                linesCols = [linesCols;linesCols];
                stepY = newSize-1-offsetY;
                linesRows = [1 newSize-offsetY : stepY : ((newSize-1)*cutoutsINrowY-offsetY*cutoutsINrowY)];
                linesRows = [linesRows;linesRows];
    
                % End of calculation like in CNN recognition
                
                colsX = [zeros(size(linesRows,2),1)' + 1; zeros(size(linesRows,2),1)' + originalSizeX];
                colsY = [zeros(size(linesCols,2),1)' + 1; zeros(size(linesCols,2),1)' + originalSizeY];
    
                linesVisible = 'on';
                % Print verticals
                app.GridVertLinesHandler = line(colsX, linesRows, 'Color', 'w');
                % Print horizontals
                app.GridHorLinesHandler = line(linesCols, colsY, 'Color', 'w');
                if app.ShowGridNumbersECheckBox.Value
                    startX = 20;
                    x = startX : stepX : originalSizeX - newSize / 2;
                    
                    startY = 20;
                    y = startY : stepY : originalSizeY - newSize / 2;
                    sx = size(x, 2);
                    sy = size(y, 2);
                    
                    y = repmat(y, 1, sx);
                    x = repelem(x, sy);
                    textArray = arrayfun(@(a)num2str(a), (y-startY)/stepY*sx + (x-startX)/stepX, 'uni', 0);
                    textVisible = 'on';
                    app.GridTextHandler = text(x, y, textArray, 'Color', 'w');
                    
                    set(app.GridTextHandler, 'Clipping', 'on');
                end
            end                        
        end
        
        function UpdateLegacy(app)
            app.FigureHandler.Name = [app.Fname,'  ',num2str(app.ConeCount),' cones'];
%             title([app.Fname,'  ',num2str(app.ConeCount),' cones'],'Interpreter','none'), hold on
%             ax = gca;                    % get the current axis
%             app.Ttl = ax.Title;              % get the title text object
%             app.Ttl.FontSize = 14;
%             app.Ttl.BackgroundColor = [1 0 0];
%             title([app.Fname,'  ',num2str(size(app.Conelocs(app.Conelocs(:,3)~=0,1),1)),' cones'],'Interpreter','none');
        end
        % Updates Image ------------------------------------------------------------
        
        % Updates selection box
        function UpdateBox(app, old_boxposition)
            SetBox(app);
            
            minlimx_old = old_boxposition(1);
            minlimy_old = old_boxposition(2);
            maxlimx_old = minlimx_old + old_boxposition(3);
            maxlimy_old = minlimy_old + old_boxposition(4);
            
            app.Conelocs(app.Conelocs(:,1)>app.Minlimx & app.Conelocs(:,1)<=minlimx_old,3)= 1;
            app.Conelocs(app.Conelocs(:,1)<app.Maxlimx & app.Conelocs(:,1)>=maxlimx_old,3)= 1;
            app.Conelocs(app.Conelocs(:,2)>app.Minlimy & app.Conelocs(:,2)<=minlimy_old,3)= 1;
            app.Conelocs(app.Conelocs(:,2)<app.Maxlimy & app.Conelocs(:,2)>=maxlimy_old,3)= 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateImage(app);
            
            ax = gca; app.Ttl = ax.Title; app.Ttl.FontSize = 14;
            UpdateLegacy(app);
        end
        
        % Updates User Interface
        function UpdateUI(app)
            isDisabled = 'off';
            if ~isempty(app.Image) && ~isempty(app.FigureHandler)
                isDisabled = 'on';
            end
            
            mainButtonsDisabled = 'on';
            if isempty(app.Image) || isempty(app.FigureHandler)
                mainButtonsDisabled = 'off';
            end
            
            set(findall(app.EditConeLocationsPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.MarksPropertiesPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.GridPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.ImagePropertiesPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.ImproveCNNResultsPanel, '-property', 'Enable'), 'Enable', isDisabled);
            set(findall(app.VoronoiPanel, '-property', 'Enable'), 'Enable', mainButtonsDisabled);
            set(findall(app.VoronoitypeButtonGroup, '-property', 'Enable'), 'Enable', mainButtonsDisabled);
            
            app.ShowConeLocationsCheckBox.Enable = isDisabled;
            
            app.SaverecognitionMenu.Enable = mainButtonsDisabled;
        end
        
        % ----------------------- Voronoi Diagram -------------------------------------       
        % Print Voronoi diagram
        function PrintVoronoiByType(app, voronoiType, conelocs)
            disp('Voronoi')
            tic;
            vorocones = conelocs(conelocs(:,3)~=0,1:2);
            vorocones = unique(vorocones, 'rows', 'stable');
            
            if ~app.VoronoiDiagramCheckBox.Value || strcmp(voronoiType, 'Empty')
                app.VoronoiAplhaShape = [];
                app.VoronoiVerticies = [];
                
                app.VoronoiAreaColors = [];
                app.VoronoiMaxAreaPixel = [];
                app.VoronoiConeAreaColorScale = [];
                app.VoronoiAreaColorIndexes = [];
                app.VoronoiNNColors = [];
                app.VoronoiNNColorScale = [];
            end
            
            
            if size(vorocones, 1) > 3 && app.VoronoiDiagramCheckBox.Value
                %%%%%%%%%%%%%% PREPARE THE DATA %%%%%%%%%%%%%%
                % check where plots are created
                if isempty(app.VoronoiDelanayTriang)
                    app.VoronoiDelanayTriang = delaunayTriangulation(vorocones);
                else
                    oldLength = size(app.VoronoiDelanayTriang.Points, 1);
                    newLength = size(vorocones, 1);
                    coneWasDeleted = 0;
                    coneWasAdded = 0;
                    if newLength < oldLength
                        memberList = ismember(app.VoronoiDelanayTriang.Points, vorocones, 'rows');
                        changeIndexes = find(memberList == 0);
                        coneWasDeleted = 1;
                    elseif newLength > oldLength
                        newCones = vorocones(newLength - oldLength, :);
                        coneWasAdded = 1;
                    end
                    app.VoronoiDelanayTriang.Points = vorocones;
                end
                if isempty(app.VoronoiDelanayTriang)
                    return;
                end
                
                % V - verticies of polygons, C - the polygons constructed by V
                [V,C] = voronoiDiagram(app.VoronoiDelanayTriang);
                numberOfClosedPolygons = length(C);
                
                if ~strcmp(voronoiType, 'Empty')
                    % Remove 'peek' coordinates of voronoi
                    if isempty(app.VoronoiAplhaShape)
                        app.VoronoiAplhaShape = alphaShape(vorocones(:, 1), vorocones(:, 2));
                        app.VoronoiAplhaShape.Alpha = 2 * app.VoronoiAplhaShape.Alpha;
                        V(V(:, 1) == Inf | V(:, 2) == Inf, :) = -1;
                        tf = inShape(app.VoronoiAplhaShape, V(:,1), V(:, 2));
                        V(~tf, 1) = NaN;
                        V(~tf, 2) = NaN;
                        differentPointsIndexes = [];
                    else
                        if newLength > oldLength
                            tf = inShape(app.VoronoiAplhaShape, newCones(:, 1), newCones(:, 2));
                            if any(tf)
                                app.VoronoiAplhaShape.Points = vorocones;
                            end
                        end
                        V(V(:, 1) == Inf | V(:, 2) == Inf, :) = -1;
                        app.VoronoiVerticies(isnan(app.VoronoiVerticies)) = -1;
                        listOfMemberLogic = ismember(V, app.VoronoiVerticies, 'rows');
                        indexes = find(listOfMemberLogic == 0);
                        different = V(indexes, :);
                        tf = inShape(app.VoronoiAplhaShape, different(:, 1), different(:, 2));
                        differentPointsIndexes = indexes(tf);
                        V(V == -1) = NaN;
                        V(indexes(~tf), :) = NaN;
                    end

                    colorsNNisCreated = 0;
                    colorsCAisCreated = 0;
                    % get color scale
                    if strcmp(voronoiType, 'Number of Neighbors') && isempty(app.VoronoiNNColorScale)
                        
                        NumNeighbors = arrayfun(@(a) size(C{a},2), 1 : numberOfClosedPolygons);
                        
                        numcolorsNN = app.VoronoiMaxNN - app.VoronoiMinNN;  % ********* change from 15
                        
                        idxNN = round(linspace(1, 256, numcolorsNN));
                        app.VoronoiNNColorScale = flipud(app.VColorMap(idxNN,:));
                        colorsNNisCreated = 1;
                        
                    elseif strcmp(voronoiType, 'Cone Area') && isempty(app.VoronoiConeAreaColorScale)
                        
                        ConeArea = arrayfun(@(a) polyarea(V(C{a},1), V(C{a},2)), 1 : numberOfClosedPolygons);
                         
                        numcolors = 25;  % ********* change from 15
                        app.VoronoiMaxAreaPixel = max(ConeArea);        % basis for dividing cone Area through numcolors
    
                        idx = round(linspace(1,256,numcolors));
                        app.VoronoiConeAreaColorScale = flipud(app.VColorMap(idx,:));
    
                        app.VoronoiAreaColorIndexes = round(linspace(min(ConeArea), app.VoronoiMaxAreaPixel, numcolors));
                        colorsCAisCreated = 1;
                        
                    end
                    
                    %%%%%%%%%%%%%% PRINT THE DIAGRAM %%%%%%%%%%%%%%
                    % Find colors for closed polygones by voronoiType
                    % VARIANTE 1: Falschfarben sind Anzahl Nachbarn
                    if strcmp(voronoiType, 'Number of Neighbors') && isempty(app.VoronoiNNColors)
                        app.VoronoiNNColors = zeros(numberOfClosedPolygons, 3);
                        logicMinNN = NumNeighbors <= app.VoronoiMinNN;
                        logicMaxNN = NumNeighbors > app.VoronoiMaxNN;
                        
                        app.VoronoiNNColors(logicMinNN, 1) = app.VoronoiNNColorScale(1, 1);
                        app.VoronoiNNColors(logicMinNN, 2) = app.VoronoiNNColorScale(1, 2);
                        app.VoronoiNNColors(logicMinNN, 3) = app.VoronoiNNColorScale(1, 3);
                        
                        app.VoronoiNNColors(logicMaxNN, 1) = app.VoronoiNNColorScale(end, 1);
                        app.VoronoiNNColors(logicMaxNN, 2) = app.VoronoiNNColorScale(end, 2);
                        app.VoronoiNNColors(logicMaxNN, 3) = app.VoronoiNNColorScale(end, 3);
                        
                        app.VoronoiNNColors(~logicMaxNN & ~logicMinNN, :) = app.VoronoiNNColorScale(NumNeighbors(~logicMaxNN & ~logicMinNN) - app.VoronoiMinNN, :);
                        
                    % VARIANTE 2: Falschfarben sind PatchgrцЯe
                    elseif strcmp(voronoiType, 'Cone Area') && isempty(app.VoronoiAreaColors)
                        app.VoronoiAreaColors = zeros(numberOfClosedPolygons, 3);
                        [~, colorInd] = min(abs(app.VoronoiAreaColorIndexes - ConeArea'), [], 2);
                        logicCA = ConeArea <= app.VoronoiMaxAreaPixel;
                        app.VoronoiAreaColors(logicCA, :) = app.VoronoiConeAreaColorScale(colorInd(logicCA), :);
                        app.VoronoiAreaColors(~logicCA, 1) = app.VoronoiConeAreaColorScale(end, 1);
                        app.VoronoiAreaColors(~logicCA, 2) = app.VoronoiConeAreaColorScale(end, 2);
                        app.VoronoiAreaColors(~logicCA, 3) = app.VoronoiConeAreaColorScale(end, 3);
                    end
                end
                
                app.VoronoiVerticies = V;
                
                % Create a matrix from list of Verice inedexes (empty values filled by NaN)
                lengthesC = cellfun('length',C);
                maxLengthesC = max(lengthesC);
                % concat all points in one array from cell array of arrays
                allPolyConcateneted = cat(2, C{:});
                % preallocate memory for matrix
                matrixC = zeros([maxLengthesC + 1, numberOfClosedPolygons]);
                % find indexes where polygons definition ends
                indexes = sub2ind(size(matrixC), lengthesC(:).' + 1, 1:numberOfClosedPolygons);
                % fill it with NaN
                matrixC(indexes) =  1;
                matrixC = cumsum(matrixC(1:end - 1,:), 1);
                matrixC(matrixC == 1) = NaN;
                % fill polygons in the rest places of matrix
                matrixC(matrixC == 0) = allPolyConcateneted;
                matrixC = matrixC';                
                
                % Plot the diagram
                if strcmp(voronoiType, 'Empty')
                    edgeColor = 'green';
                    faceColor = 'none';
                    colors = [];
                else
                    % Update colors
                    rowsToUpdate = ismember(matrixC', differentPointsIndexes);
                    rowsToUpdate = any(rowsToUpdate);
                    rowsToUpdate = find(rowsToUpdate == 1);
                    
                    if ~isempty(app.VoronoiNNColors) && ~colorsNNisCreated
                        if coneWasDeleted
                            app.VoronoiNNColors(changeIndexes, :) = [];
                        elseif coneWasAdded
                            app.VoronoiNNColors(end + oldLength - newLength, :) = [0 0 0];
                        end
                        
                        conePolysToUpdate = C(rowsToUpdate);
                        NumNeighbors = cellfun('length', conePolysToUpdate);
                        
                        logicMinNN = NumNeighbors <= app.VoronoiMinNN;
                        logicMaxNN = NumNeighbors > app.VoronoiMaxNN;
                        colorInd = NumNeighbors(~logicMaxNN & ~logicMinNN) - app.VoronoiMinNN;
                        
                        app.VoronoiNNColors(rowsToUpdate(~logicMaxNN & ~logicMinNN), :) = app.VoronoiNNColorScale(colorInd, :);
                        
                        app.VoronoiNNColors(rowsToUpdate(logicMinNN), 1) = app.VoronoiNNColorScale(1, 1);
                        app.VoronoiNNColors(rowsToUpdate(logicMinNN), 2) = app.VoronoiNNColorScale(1, 2);
                        app.VoronoiNNColors(rowsToUpdate(logicMinNN), 3) = app.VoronoiNNColorScale(1, 3);
                        
                        app.VoronoiNNColors(rowsToUpdate(logicMaxNN), 1) = app.VoronoiNNColorScale(end, 1);
                        app.VoronoiNNColors(rowsToUpdate(logicMaxNN), 2) = app.VoronoiNNColorScale(end, 2);
                        app.VoronoiNNColors(rowsToUpdate(logicMaxNN), 3) = app.VoronoiNNColorScale(end, 3);
                    end
                    
                    if ~isempty(app.VoronoiAreaColors) && ~colorsCAisCreated
                        if coneWasDeleted
                            app.VoronoiAreaColors(changeIndexes, :) = [];
                        elseif coneWasAdded
                            app.VoronoiAreaColors(end + newLength - oldLength, :) = [0 0 0];
                        end
                        
                        conePolysToUpdate = C(rowsToUpdate);
                        Areas = cellfun(@(a) polyarea(V(a, 1), V(a, 2)), conePolysToUpdate);
                        [~, colorInd] = min(abs(app.VoronoiAreaColorIndexes - Areas), [], 2);
                        logicCA = Areas <= app.VoronoiMaxAreaPixel;
                        
                        app.VoronoiAreaColors(rowsToUpdate(logicCA), :) = app.VoronoiConeAreaColorScale(colorInd(logicCA), :);
                        app.VoronoiAreaColors(rowsToUpdate(~logicCA), 1) = app.VoronoiConeAreaColorScale(end, 1);
                        app.VoronoiAreaColors(rowsToUpdate(~logicCA), 2) = app.VoronoiConeAreaColorScale(end, 2);
                        app.VoronoiAreaColors(rowsToUpdate(~logicCA), 3) = app.VoronoiConeAreaColorScale(end, 3);
                    end
                    
                    if strcmp(voronoiType, 'Number of Neighbors')
                        colors = app.VoronoiNNColors;
                    elseif strcmp(voronoiType, 'Cone Area')
                        colors = app.VoronoiAreaColors;
                    end
                    edgeColor = 'black';
                    faceColor = 'flat';
                end
                
                if isempty(app.VoronoiPatchHandler) || ~isvalid(app.VoronoiPatchHandler)
                    app.VoronoiPatchHandler = patch('Faces', [],'Vertices', []);
                end
                
                set(app.VoronoiPatchHandler, 'Faces', matrixC, 'Vertices', app.VoronoiVerticies, ...
                    'FaceVertexCData', colors, 'FaceColor', faceColor, ...
                    'EdgeColor', edgeColor, 'Visible', 'on');
            else
                if ~isempty(app.VoronoiPatchHandler) && isvalid(app.VoronoiPatchHandler)
                    set(app.VoronoiPatchHandler, 'Visible', 'off');
                end
            end
            
            toc;
        end
        
        function PrintVoronoi(app, voronoiType)
            if app.VoronoiPlot == 1
%                 PrintImage(app, app.Image);
                UpdateImage(app);
                app.VoronoiPlot = 0;
                UpdateUI(app);
%                 PrintVoronoiByType(app, voronoiType, app.Conelocs);
            else
                app.VoronoiPlot = 1;
                UpdateUI(app);
                UpdateImage(app);
            end
        end

        % ------------------------- Find Cones ------------------------------
        % Finds cone locations by FastPeakFind algorithm
        function FastPeakFinder(app)
            p = FastPeakFind(app.Image);
            app.Conelocs(:,1) = p(1:2:end);
            app.Conelocs(:,2) = p(2:2:end);
            app.Conelocs(:,3) = 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end
        
        % Find cone locations by CNN
        function CnnFinder(app, cnnFloderName, cnnCalcType)
            % DataSet = '14111';  % dataSet specifies trained CNN (grader 1 trained network - 14(x16) training images and 1(x16) validation image)
            % DataSet = '42';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
           
            fn = {'g1+cunefare', 'alex training set'};
            [indx,tf] = listdlg('Name', 'Cone Finder Data Sets', 'PromptString', {'Select a cone finder data set.'},...
                'SelectionMode','single','ListString', fn);
            
            % if user clicked 'Cancel'
            if ~tf
                DataSet = 'alex training set';
            else
                switch indx
                    case 1 % 'g1+cunefare'
                        DataSet = 'g1+cunefare';
                        
                    case 2 % 'alex training set'
                        DataSet = 'alex training set';
                end % switch
            end
            
            ImageDir = [app.Pname app.Fname];
            startTimeCNN = clock;
            fprintf('startTimeCNN: %s \n', sprintf('%g ', startTimeCNN));
            [app.Conelocs] = RunCNNnewSet_automatic(DataSet, ImageDir, app.BoxPosition, app.Image, cnnCalcType, cnnFloderName);
            endTimeCNN = clock;
            fprintf('endTimeCNN: %s \n', sprintf('%g ', endTimeCNN));
            toc;
            disp(' ');
            disp(' ');
            
            app.Conelocs = unique(app.Conelocs,'rows', 'stable');
            app.Conelocs(:,3) = 1;
            
            app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
            app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
            app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            plot(app.Conelocs(app.Conelocs(:,3)~=0,1), app.Conelocs(app.Conelocs(:,3)~=0,2), app.MarkType, 'MarkerSize', app.MarkerSize)
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
        end
        
        % Loads Data
        function LoadData(app)
            load([app.Pname app.Fname], 'I', 'conelocs', 'boxposition');
            app.Image = I;
            app.Conelocs = conelocs;
            app.BoxPosition = boxposition;
            
            SetBox(app);
            
            app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
            
            if size(app.Conelocs, 2) < 3
                app.Conelocs(:, 3) = 1;
                app.Conelocs(app.Conelocs(:,1)<=app.Minlimx,3)= 0;
                app.Conelocs(app.Conelocs(:,1)>=app.Maxlimx,3)= 0;
                app.Conelocs(app.Conelocs(:,2)<=app.Minlimy,3)= 0;
                app.Conelocs(app.Conelocs(:,2)>=app.Maxlimy,3)= 0;
            end
            
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            app.MarkerSize = 4;
            
            CreateFigure(app);

            UpdateLegacy(app);
        end
        
        function SetBox(app)
            app.Minlimx = app.BoxPosition(1);
            if app.Minlimx < 0
                app.Minlimx = 0;
            end
            
            app.Minlimy = app.BoxPosition(2);
            if app.Minlimy < 0
                app.Minlimy = 0;
            end
            
            [h, w, ~] = size(app.Image);
            app.Maxlimx = app.Minlimx + app.BoxPosition(3);
            if app.Maxlimx > w
                app.Maxlimx = w;
            end
            
            app.Maxlimy = app.Minlimy + app.BoxPosition(4);
            if app.Maxlimy > h
                app.Maxlimy = h;
            end
        end
        
        % Removes Cones outside fovea image (on black boards)
        function RemoveBlackCones(app)
            conelocs = app.Conelocs(:, 1:2);
            image = app.Image;
            sizeImage = size(image);
            conelocsY = int64(conelocs(:,1));
            conelocsX = int64(conelocs(:,2));
            values = app.Image(sub2ind(sizeImage, conelocsX', conelocsY'));
            app.Conelocs = app.Conelocs(values > 3, :);
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            UpdateLegacy(app);
        end
        
        function RemoveDoubleCones(app)
%             offset = 10;
%             [rows, columns, ~] = size(app.Image);
%             x = 0;
%             y = 0;
%             while x <= columns
%                 x = x + 150;
%                 y = 0;
%                 while y <= rows
%                     y = y + 150;
%                     conelocsY = app.Conelocs(:,1);
%                     conelocsX = app.Conelocs(:,2);
%                 end
%             end
            conelocsY = app.Conelocs(:,1);
            conelocsX = app.Conelocs(:,2);
            R_all = [conelocsX, conelocsY];
            Df = 1;
            
            % calculate distance matrix for every point pair
            dist = pdist2(R_all, R_all); % sqrt(sum((A-B).^2 , 3));
            
            % mask the distance to the point itself and to all previous points
            dist(1:(1+size(dist,1)):end) = inf;
            dist(logical(triu(ones(size(dist))))) = inf;
            % example
            % 0 0 0 0
            % d 0 0 0
            % d d 0 0
            % d d d 0
            
            L = dist<1.5*Df;
            L = any(L,1);
            
            app.Conelocs(L,:) = [];
            app.ConeCount = size(app.Conelocs(app.Conelocs(:,3)~=0,1),1);
            app.TotalConeCount = size(app.Conelocs,1);
            
            UpdateLegacy(app);
        end
        
        function CreateFigure(app)
           app.FigureHandler = figure('MenuBar', 'none', ...
                                      'ToolBar', 'none', ...
                                      'DockControls', 'off', ...
                                      'NumberTitle', 'off');
           app.ImageShowHandler = imshow(app.Image);
           hold on;
        end
        
        function CreateScrollMinimap(app)
             if isempty(app.ScrollPanelHandler)
               app.ScrollPanelHandler = imscrollpanel(app.ImageShowHandler.Parent.Parent, app.ImageShowHandler); % Handle to scroll panel.
               set(app.ScrollPanelHandler,'Units','normalized',...
                   'Position',[0 0 1 1])
            
               % Add a Magnification Box and an Overview tool.
               app.MagBoxHandler = immagbox(app.ImageShowHandler.Parent.Parent, app.ImageShowHandler);
               pos = get(app.MagBoxHandler,'Position');
               set(app.MagBoxHandler,'Position',[0 0 pos(3) pos(4)]);
               app.MiniMapHandler = imoverview(app.ImageShowHandler);
               set(app.MiniMapHandler, 'MenuBar', 'none');
               set(app.MiniMapHandler, 'ToolBar', 'none');
               app.MiniMapHandler.Resize = 'off';
               
            
               % Get the scroll panel API to programmatically control the view.
               app.ScrollPanelApi = iptgetapi(app.ScrollPanelHandler); 
               
               figure(app.FigureHandler);
             end
        end
    end
    
    methods (Access = private)
        function SubscribeEvents(app)
            [h, w, ~] = size(app.Image);
            app.ImgWidth = w;
            app.ImgHeight = h;
            
            
            set(app.FigureHandler, 'WindowScrollWheelFcn', @app.ZoomMouseWheelCallback);
            set(app.FigureHandler, 'WindowButtonDownFcn', @app.MouseButtonDownCallback);
            set(app.FigureHandler, 'WindowButtonUpFcn', @app.MouseButtonUpCallback);
            set(app.FigureHandler, 'WindowKeyPressFcn', @app.KeyPressCallback);
            set(app.FigureHandler, 'WindowKeyReleaseFcn', @app.KeyReleaseCallback);
            set(app.FigureHandler, 'CloseRequestFcn', @app.FigCloseRequestFcn);
        end
        
        function UnsubscribeEvents(~, hFig)
            set(hFig, 'WindowScrollWheelFcn', []);
            set(hFig, 'WindowButtonDownFcn', []);
            set(hFig, 'WindowButtonUpFcn', []);
            set(hFig, 'WindowKeyPressFcn', []);
            set(hFig, 'WindowKeyReleaseFcn', []);
        end
        
        % Applies zoom
        function ZoomMouseWheelCallback(app, ~, cbdata)
            scrollChange = cbdata.VerticalScrollCount; % -1: zoomIn, 1: zoomOut
            currentMag = app.ScrollPanelApi.getMagnification();
            app.ScrollPanelApi.setMagnification(currentMag - 0.1 * scrollChange);
        end
        
        
        function MouseButtonDownCallback(app, hObj, event)
            if app.IsBusy
                return;
            end
            
            clickType = event.Source.SelectionType;
            isAltPressed = any(ismember(get(hObj,'CurrentModifier'), 'alt'));

            % If 'Alt' is pressed
            if isAltPressed && ~isempty(app.ImageShowHandler) && isvalid(app.ImageShowHandler)
                seedPt = get(app.ImageShowHandler.Parent, 'CurrentPoint'); 
                seedPt = seedPt(1, :);
                x = seedPt(1);
                y = seedPt(2);
            
                % Mouse Left Click
                if strcmp(clickType, 'normal')
                    MarkCone(app, x, y);
                end
                
                % Mouse Right Click (or Ctrl + Left Click)
                if strcmp(clickType, 'alt')
                    RemoveMark(app, x, y);
                end
            end
            
            % if clicked mouse button 3 (wheel)
            if (strcmp(clickType, 'extend'))

                guiArea = hittest(hObj);
                parentAxes = ancestor(guiArea, 'axes');

                % if the mouse is over the desired axis, trigger the pan fcn
                if ~isempty(parentAxes)
                    app.StartPan(hObj)
                else
                    setptr(event.Source, 'forbidden')
                end
            end
        end
                
        function MouseButtonUpCallback(app, ~, ~)
            if app.IsBusy
                return;
            end
            
            app.StopPan();
        end
        
            
        function KeyPressCallback(app, ~, event)
            if app.IsBusy
                return;
            end
            
            if any(ismember(event.Modifier, 'alt'))
                setptr(gcf, 'cross');
            end
            button = event.Key;
        
            switch button
                % Darken image "D"
                case 'd'
                    if app.VoronoiPlot == 1
                        if (app.BrightnessDBSlider.Value < 2.0)
                            if (app.BrightnessDBSlider.Value + 0.1 <= 2.0)
                                app.BrightnessDBSlider.Value = app.BrightnessDBSlider.Value + 0.1;
                            else
                                app.BrightnessDBSlider.Value = 2.0;
                            end
                        end
                        newEvent.Value = app.BrightnessDBSlider.Value;
                        BrightnessDBSliderValueChanging(app, newEvent);
                    end
                    
                % Brighten image "B"
                case 'b'
                    if app.VoronoiPlot == 1
                        if (app.BrightnessDBSlider.Value > 0.0)
                            if (app.BrightnessDBSlider.Value - 0.1 >= 0.0)
                                app.BrightnessDBSlider.Value = app.BrightnessDBSlider.Value - 0.1;
                            else
                                app.BrightnessDBSlider.Value = 0.0;
                            end
                        end
                        newEvent.Value = app.BrightnessDBSlider.Value;
                        BrightnessDBSliderValueChanging(app, newEvent);
                    end
                    
                % Reset image brightness "R"
                case 'r'
                    if app.VoronoiPlot == 1
                        app.BrightnessDBSlider.Value = 1.0;
                        ResetBrightnessRButtonValueChanged(app, event);
                    end
                                        
                % Log filter image "L"
                case 'l'
                    if app.VoronoiPlot == 1
                        app.LogfilterLCheckBox.Value = ~app.LogfilterLCheckBox.Value;
                        LogImageLCheckBoxValueChanged(app, event);
                    end
                    
                % Show image "I"
                case 'i'
                    if app.VoronoiPlot == 1
                        app.ShowImageICheckBox.Value = ~app.ShowImageICheckBox.Value;
                        ShowimageICheckBoxValueChanged(app, event);
                    end
                    
                % Show Marks "T"
                case 't'
                    if app.VoronoiPlot == 1
                        app.ShowMarkedLocationsTCheckBox.Value = ~app.ShowMarkedLocationsTCheckBox.Value;
                        ShowMarkedLocationsTSwitchValueChanged(app, event);
                    end
                    
                % Circle marks "C"
                case 'c'
                    if app.VoronoiPlot == 1
                        app.CircleCButton.Value = ~app.CircleCButton.Value;
                        ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    end
                    
                % Cross marks "X"
                case 'x'
                    if app.VoronoiPlot == 1
                        app.CrossXButton.Value = ~app.CrossXButton.Value;
                        ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    end
                    
                % Dot marks "Z"
                case 'z'
                    if app.VoronoiPlot == 1
                        app.DotZButton.Value = ~app.DotZButton.Value;
                        ConeMarksTypeButtonGroupSelectionChanged(app, event);
                    end
                    
                % Autodetected marks "1"
                case '1'
                    if app.VoronoiPlot == 1
                        app.Autodetect1Button.Value = ~app.Autodetect1Button.Value;
                        ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Userdetected marks "2"
                case '2'
                    if app.VoronoiPlot == 1
                        app.Userdetect2Button.Value = ~app.Userdetect2Button.Value;
                        ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Auto + User detected marks "3"
                case '3'
                    if app.VoronoiPlot == 1
                        app.AutoUser3Button.Value = ~app.AutoUser3Button.Value;
                        ShowMarksOptionsButtonGroupSelectionChanged(app, event);
                    end
                    
                % Highlight autodetected marks "0"
                case '0'
                    if app.VoronoiPlot == 1
                        app.HighlightAutodetectedCones0CheckBox.Value = ...
                            ~app.HighlightAutodetectedCones0CheckBox.Value;
                        HighlightAutodetectedCones0CheckBoxValueChanged(app, event);
                    end
                    
                % Show grid "Q"
                case 'q'
                    if app.VoronoiPlot == 1
                        app.ShowGridQCheckBox.Value = ~app.ShowGridQCheckBox.Value;
                        ShowGridQCheckBoxValueChanged(app, event);
                    end
                    
                % Show grid numbers "E"
                case 'e'
                    if app.VoronoiPlot == 1
                        app.ShowGridNumbersECheckBox.Value = ~app.ShowGridNumbersECheckBox.Value;
                        ShowGirdNumbersECheckBoxValueChanged(app, event);
                    end
                    
                % Save current locations "SPACE"
                case 'space'
                    SaveCurrentLocationsSPACEButtonPushed(app, event);
                    
                % Delete selected locations "Del" ("entf")
                case 'delete'
                    if app.VoronoiPlot == 1
                        DeletedSelectedConesDelButtonPushed(app, event);
                    end
                    
                % Plot voronoi digram for 'Cone Area', "V"
                case 'v'
                    if strcmp(app.ConeAreaButton.Tag, app.VoronoiType) && app.VoronoiDiagramCheckBox.Value 
                        app.VoronoiDiagramCheckBox.Value = 0;
                        VoronoiDiagramCheckBoxValueChanged(app, event);
                    else
                        app.VoronoitypeButtonGroup.SelectedObject = app.ConeAreaButton;
                        if ~app.VoronoiDiagramCheckBox.Value 
                            app.VoronoiDiagramCheckBox.Value = 1;
                        end
                        VoronoitypeButtonGroupSelectionChanged(app, event);
                    end
%                     PrintVoronoi(app, 'Cone Area');
                    
                % Plot voronoi digram for 'Number of Neighbors', "N"
                case 'n'
                    if strcmp(app.NumberofNeighborsButton.Tag, app.VoronoiType) && app.VoronoiDiagramCheckBox.Value 
                        app.VoronoiDiagramCheckBox.Value = 0;
                        VoronoiDiagramCheckBoxValueChanged(app, event);
                    else
                        app.VoronoitypeButtonGroup.SelectedObject = app.NumberofNeighborsButton;
                        if ~app.VoronoiDiagramCheckBox.Value 
                            app.VoronoiDiagramCheckBox.Value = 1;
                        end
                        VoronoitypeButtonGroupSelectionChanged(app, event);
                    end
            end
               
            UpdateUI(app);
        end
    
        function KeyReleaseCallback(app, ~, event)
            if app.IsBusy
                return;
            end
            
            if ~any(ismember(event.Modifier, 'alt'))
                setptr(gcf, 'arrow');
            end
        end
        
        
        function FigCloseRequestFcn(app, hFig, ~)
            UnsubscribeEvents(app, hFig);
            delete(hFig);
            
            if isvalid(app)
                app.FigureHandler = [];
                app.ScrollPanelApi = [];
                app.ImageShowHandler = [];
                app.MagBoxHandler = [];
                app.MiniMapHandler = [];
                app.ScrollPanelHandler = [];
                UpdateUI(app);
            end
        end
        
        %% AXIS PANNING FUNCTIONS
    
        % Call this Fcn in your 'WindowButtonDownFcn'
        % Take in desired Axis to pan
        % Get seed points & assign the Panning Fcn to top level Fig
        function StartPan(app, hFig)
            % Parent Fig
            %hFig = ancestor(hAx, 'Figure', 'toplevel');   
    
            % Get init mouse position
            seedPt = get(hFig, 'CurrentPoint'); 
            % Keep only 1st point
            seedPt = seedPt(1, :); 
    
            
            start_pos_im = app.ScrollPanelApi.getVisibleImageRect();
            start_pos_im(3:4) = [];

            screenPerImagePixels = app.ScrollPanelApi.getMagnification();

            set(hFig,'WindowButtonMotionFcn', {@app.PanningCallback, screenPerImagePixels, seedPt, start_pos_im});
            % Assign 'Panning' cursor
            setptr(hFig, 'hand');
        end
    
    
        % Call this Fcn in your 'WindowButtonUpFcn'
        function StopPan(~)
            set(gcbf,'WindowButtonMotionFcn',[]);
            setptr(gcbf,'arrow');
        end
    
    
        % Controls the real-time panning on the desired axis
        function PanningCallback(app, ~, ~, screenPerImagePixels, seedPt, start_pos_im)            
            % The closedhand pointer is not provided by the HG figure window. Since we
            % are using setptr, disable the pointer manager to prevent manager
            % from caching hand cursors.
            iptPointerManager(app.FigureHandler,'Disable');
            setptr(app.FigureHandler,'closedhand')

            % Note: we must use the figure CurrentPoint property to make sure
            % the calculation of the deltas is invariant to the image being dragged.
            new_point = get(app.FigureHandler, 'CurrentPoint');
            cp = new_point(1,1:2);
            delta_x_screen = cp(1) - seedPt(1);
            delta_y_screen = cp(2) - seedPt(2);
        
            new_pos_im =  start_pos_im - ...
                [delta_x_screen -delta_y_screen]/screenPerImagePixels;

            new_pos_im = app.ConstrainDrag(new_pos_im);
            app.ScrollPanelApi.setVisibleLocation(new_pos_im(1),new_pos_im(2));
        end
        
        function pos = ConstrainDrag(app, proposedPos)
    
            x = proposedPos(1);
            y = proposedPos(2);
    
            r = app.ScrollPanelApi.getVisibleImageRect();
            w = r(3);
            h = r(4);
            
            % get image model and cdata sizes
            imModel = getimagemodel(app.ImageShowHandler);
            imW = getImageWidth(imModel);
            imH = getImageHeight(imModel);
            
            % get spatial span of data
            xdata = app.getXData(app.ImageShowHandler);
            ydata = app.getYData(app.ImageShowHandler);
            
            % compute pixel span in each direction
            dx = images.internal.getDeltaOnePixel(xdata,imW);
            dy = images.internal.getDeltaOnePixel(ydata,imH);
            
            left_edge   = xdata(1) - dx/2;
            right_edge  = xdata(2) + dx/2;
            top_edge    = ydata(1) - dy/2;
            bottom_edge = ydata(2) + dy/2;
            
            x = min(right_edge  - w, max(x,left_edge) );
            y = min(bottom_edge - h, max(y,top_edge) );
     
            pos = [x y];
    
        end
            
        function xdata = getXData(~, handle)
            
            xdata = get(handle,'XData');
            
            if isscalar(xdata)
                xdata = [xdata xdata];
            end
        end
        
        function ydata = getYData(~, handle)
            
            ydata = get(handle,'YData');
            
            if isscalar(ydata)
                ydata = [ydata ydata];
            end
        end
        
        function IsCanceled = NewFileOpening(app, fileTypes)
            format shortg
            app.Conelocs = [];
            app.Image = []; 
           
            % add folder with CNN_Code to matlab search for current session
            addpath(fullfile(fileparts(mfilename('fullpath')), 'CNN_Code'));
            % add folder with Utilities to matlab search for current session
            addpath(fullfile(fileparts(mfilename('fullpath')), 'Utilities'));
            
            try 
                close(gcf);
                app.FigureHandler = [];
            catch 
            end
            
            % User input - Select files to analyse
            % Select tiff to analyze, or mat file in case revisiting already counted tiff
            start_path = ([cd filesep]);
            
            % file selection GUI
            [fnames, app.Pname] = uigetfile(fileTypes, 'Select file(s) to mark', start_path, 'MultiSelect', 'off');
            
            IsCanceled = 0;
            % checks if user cancelled
            if isequal(fnames,0) || isequal(app.Pname,0)
                % display a message in the commmand window
                disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                % if cancelled exit script
                IsCanceled = 1;
            end
            
            app.Fname = fnames;
        end
        
        function SetupColorMap(app)
            % Colormap for Voronoi patches
            try
                colormaps_mpl2019;
                app.VColorMap = colormap(viridis); 
            catch
                disp('colormap is not found. Default colormap is used.');
                app.VColorMap = colormap("parula");
            end
            close(gcf);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(findall(app.ConeFinderUIFigure, '-property', 'Enable'), 'Enable', 'off');
            set(findall(app.FileMenu, '-property', 'Enable'), 'Enable', 'on');
            app.SaverecognitionMenu.Enable = 'off';
            
            SetupColorMap(app);
        end

        % Callback function
        function VoronoiVNButtonPushed(app, event)
            voronoi_type = [];
            if app.VoronoiPlot
                % Get voronoi type
                voronoi_type = questdlg('Select coloring for Voronoi patches', 'Coloring Voronois', 'Cone Area', ...
                    'Number of Neighbors', 'Cone Area');    
            end
            
            PrintVoronoi(app, voronoi_type);
        end

        % Button pushed function: ResetBrightnessRButton
        function ResetBrightnessRButtonValueChanged(app, event)
            app.BrightnessDBSlider.Value = 1.0;
            app.Brightness = 1.0;
            UpdateImage(app);
        end

        % Value changed function: LogfilterLCheckBox
        function LogImageLCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ShowMarkedLocationsTCheckBox
        function ShowMarkedLocationsTSwitchValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ShowImageICheckBox
        function ShowimageICheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changing function: BrightnessDBSlider
        function BrightnessDBSliderValueChanging(app, event)
            app.Brightness = event.Value;
            UpdateImage(app);
        end

        % Selection changed function: ConeMarksTypeButtonGroup
        function ConeMarksTypeButtonGroupSelectionChanged(app, event)
            if app.CircleCButton.Value
                app.Marker = 'o';
                
                % yellow
                app.Color = [1 1 0];
                app.MarkType = 'oy';
                app.MarkerSize = 5;
            elseif app.CrossXButton.Value
                app.MarkType = 'xy';
                
                app.Marker = 'x';
                % yellow
                app.Color = [1 1 0];
                app.MarkerSize = 5;
            else
                app.MarkType = '.y';
                
                app.Marker = '.';
                % yellow
                app.Color = [1 1 0];
                app.MarkerSize = 2;
            end
            
            UpdateImage(app);
        end

        % Selection changed function: ShowMarksOptionsButtonGroup
        function ShowMarksOptionsButtonGroupSelectionChanged(app, event)
            if app.Userdetect2Button.Value
                app.ShowConesByDetectType = 1;
            elseif app.Autodetect1Button.Value
                app.ShowConesByDetectType = 2;
            else
                app.ShowConesByDetectType = 0;
            end
            
            UpdateImage(app);
        end

        % Value changed function: 
        % HighlightAutodetectedCones0CheckBox
        function HighlightAutodetectedCones0CheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Menu selected function: SaverecognitionMenu
        function SaveCurrentLocationsSPACEButtonPushed(app, event)
            SaveCurrentLocations(app);
        end

        % Menu selected function: ExitMenu
        function ExitButtonPushed(app, event)
            if ~isempty(app.Conelocs) && ~isempty(app.Image)
                close_dialog = questdlg('Do you want to save before quitting?',...
                    'Close analysis window', 'Yes', 'No', 'Yes');
                
                if strcmp(close_dialog, 'Yes')
                    SaveCurrentLocations(app);
                end
            end
            
            close(app.ConeFinderUIFigure);
        end

        % Button pushed function: ChangeBoxSizeButton
        function ChangeBoxSizeButtonPushed(app, event)
            app.IsBusy = 1;
            
            change_box = questdlg('Select new Box parameters', 'Change Size of Box/Rectangle', 'Keep current box', ...
                'Select new 500x500p box', 'Enter new box-parameters', 'Keep current box');
            
            if strcmp(change_box, 'Select new 500x500p box')
                old_boxposition = app.BoxPosition;
                hi = drawrectangle('Position',[0 0 500 500]); % imrect(gca,[0 0 500 500]);
                wait(hi);
                app.BoxPosition = hi.Position;
                
                delete(hi);
                UpdateBox(app, old_boxposition);
            elseif strcmp(change_box, 'Enter new box-parameters')
                old_boxposition = app.BoxPosition;
                app.BoxPosition = input('New box-parameters ([x y x-range y-range]: ');
                UpdateBox(app, old_boxposition);
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: DeletedSelectedConesDelButton
        function DeletedSelectedConesDelButtonPushed(app, event)
            app.IsBusy = 1;
            
            delBox = drawrectangle(); % imrect(gca);
            wait(delBox);
            
            box_delCones = delBox.Position;
            if ~isempty(box_delCones)
                mindelx = box_delCones(1);
                mindely = box_delCones(2);
                maxdelx = mindelx + box_delCones(3);
                maxdely = mindely + box_delCones(4);
                
                app.Conelocs((app.Conelocs(:,1)>=mindelx & app.Conelocs(:,1)<=maxdelx & app.Conelocs(:,2)>=mindely & app.Conelocs(:,2)<=maxdely),:)= [];
                
                delete(delBox);
                
                UpdateImage(app);
                UpdateLegacy(app);
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: AddConelocsbyfastpeakfindButton
        function AddConelocsbyfastpeakfindButtonPushed(app, event)
            app.IsBusy = 1;
            
            add_conelocs = [];
            
            fPFBox = drawrectangle(); % imrect(gca);
            wait(fPFBox);
            
            box_addCones = fPFBox.Position;
            
            if ~isempty(box_addCones)
                [rows, cols] = size(app.Image);
                
                minaddx = floor(box_addCones(1));
                minaddy = floor(box_addCones(2));
                if minaddx < 1
                    minaddx = 1;
                end
                if minaddy < 1
                    minaddy = 1;
                end
                
                maxaddx = minaddx + ceil(box_addCones(3));
                maxaddy = minaddy + ceil(box_addCones(4));
                if maxaddx > cols
                    maxaddx = cols;
                end
                if maxaddy > rows
                    maxaddy = rows;
                end
                
                p = FastPeakFind(app.Image(minaddy:maxaddy, minaddx:maxaddx));
                add_conelocs(:,1) = p(1:2:end)+minaddx-1;
                add_conelocs(:,2) = p(2:2:end)+minaddy-1;
                add_conelocs(:,3) = 3;
                
                app.Conelocs = [app.Conelocs; add_conelocs];
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                
                delete(fPFBox);
                
                UpdateImage(app);
                UpdateLegacy(app);
            end
            
            app.IsBusy = 0;
        end

        % Button pushed function: AddConelocsOutsideBoxCNNButton
        function AddConelocsOutsideBoxCNNButtonPushed(app, event)
            app.IsBusy = 1;
            
            fPFBox = drawrectangle(); % imrect(gca);
            wait(fPFBox);
            
            box_addCones = fPFBox.Position;

            if ~isempty(box_addCones)
                minaddx = box_addCones(1);
                minaddy = box_addCones(2);
                maxaddx = minaddx + box_addCones(3);
                maxaddy = minaddy + box_addCones(4);
                
                app.Conelocs(app.Conelocs(:,1)>=minaddx & ...
                             app.Conelocs(:,1)<=maxaddx & ...
                             app.Conelocs(:,2)>=minaddy & ...
                             app.Conelocs(:,2)<=maxaddy,3)= 3;
                
                app.Conelocs = unique(app.Conelocs, 'rows', 'stable');
                
                delete(fPFBox);
                
                UpdateImage(app);
                UpdateLegacy(app);
            end
            
            app.IsBusy = 0;
        end

        % Close request function: ConeFinderUIFigure
        function ConeFinderUIFigureCloseRequest(app, event)
            app.IsClosing = 1;
            try
                close(app.FigureHandler);
            catch
            end
            delete(app);
        end

        % Value changed function: ShowGridQCheckBox
        function ShowGridQCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ShowGridNumbersECheckBox
        function ShowGirdNumbersECheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Value changed function: ShowConeLocationsCheckBox
        function ShowConeLocationsCheckBoxValueChanged(app, event)
            app.PrintConesOnVoronoi = app.ShowConeLocationsCheckBox.Value;
        end

        % Button pushed function: DelOutsideImageButton
        function DelOutsideImageButtonPushed(app, event)
            app.RemoveBlackCones();
            UpdateImage(app);
        end

        % Button pushed function: DelDoublesButton
        function DelDoublesButtonPushed(app, event)
            app.RemoveDoubleCones();
            UpdateImage(app);
        end

        % Value changed function: LaplacianofGaussianfilterCheckBox
        function LapOfGausCheckBoxValueChanged(app, event)
            UpdateImage(app);
        end

        % Menu selected function: NewrecognitionMenu
        function NewrecognitionMenuSelected(app, event)
            IsCanceled = NewFileOpening(app, '*.tiff;*.tif;');
            
            % Prepare file to start/continue cone marking
            if ~IsCanceled
                app.Image = imread([app.Pname app.Fname]);
                CreateFigure(app);
                
                box_size = questdlg('Select box size', 'Define window size for analysis', '200x200 pixel', ...
                    '512x512  pixel', '1024x1024  pixel', '1024x1024  pixel');
                if strcmp(box_size, '200x200 pixel')
                    hi = drawrectangle('Position',[0 0 200 200]); %imrect(gca,[0 0 200 200]);
                elseif strcmp(box_size, '512x512  pixel')
                    hi = drawrectangle('Position',[0 0 512 512]); %hi = imrect(gca,[0 0 512 512]);
                elseif strcmp(box_size, '1024x1024  pixel')
                    hi = drawrectangle('Position',[0 0 1024 1024]); %hi = imrect(gca,[0 0 1024 1024]);
                end
                
                wait(hi);
                app.BoxPosition = hi.Position;
                delete(hi);
                
                SetBox(app);
                                
                PrintImage(app, app.Image);
                rectangle('Position', app.BoxPosition,'EdgeColor',[1 0 1]);
                
                % QUESTDLG for use of the FastPeakFinder
                fn = {'Fast Peak Finder', 'CNN v23 (gpu)', 'CNN v23 (cpu)', 'CNN v25 (gpu)', 'CNN v25 (cpu)'};
                [indx,tf] = listdlg('Name', 'Cone Finder Algroithms', 'PromptString', {'Select a cone finder algorithm.'},...
                    'SelectionMode','single','ListString', fn);
                
                % if user clicked 'Cancel'
                if ~tf
                    app.ConeCount = 0;
                else
                    switch indx
                        case 1 % 'Fast Peak Finder'
                            FastPeakFinder(app);
                            
                        case 2 % 'CNN v23 (gpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta23', 'gpu');
                            
                        case 3 % 'CNN v23 (cpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta23cpu', 'cpu');
                            
                        case 4 % 'CNN v25 (gpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta25', 'gpu');
                            
                        case 5 % 'CNN v25 (cpu)'
                            CnnFinder(app, 'matconvnet-1.0-beta25cpu', 'cpu');
                    end % switch
                end
                
                UpdateImage(app);
                UpdateLegacy(app);
                
                SubscribeEvents(app);
            end
            
            UpdateUI(app);
        end

        % Menu selected function: OpenrecognitionMenu
        function OpenrecognitionMenuSelected(app, event)
            IsCanceled = NewFileOpening(app, '*.mat;');
            
            if ~IsCanceled
                % load data
                LoadData(app); 
                
                UpdateImage(app);
                UpdateLegacy(app);
                
                SubscribeEvents(app);
            end
            
            UpdateUI(app);
        end

        % Value changed function: VoronoiDiagramCheckBox
        function VoronoiDiagramCheckBoxValueChanged(app, event)
            app.VoronoiPlot = app.VoronoiDiagramCheckBox.Value;
            PrintVoronoi(app, app.VoronoitypeButtonGroup.SelectedObject.Tag);
        end

        % Selection changed function: VoronoitypeButtonGroup
        function VoronoitypeButtonGroupSelectionChanged(app, event)
            app.VoronoiType = app.VoronoitypeButtonGroup.SelectedObject.Tag;
            if app.VoronoiDiagramCheckBox.Value
                VoronoiDiagramCheckBoxValueChanged(app, event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConeFinderUIFigure = uifigure('Visible', 'off');
            app.ConeFinderUIFigure.Name = 'Cone Finder';
            app.ConeFinderUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConeFinderUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ConeFinderUIFigure);
            app.FileMenu.Text = 'File';
            app.NewrecognitionMenu = uimenu(app.FileMenu);
            app.NewrecognitionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewrecognitionMenuSelected, true);
            app.NewrecognitionMenu.Text = 'New recognition';
            app.OpenrecognitionMenu = uimenu(app.FileMenu);
            app.OpenrecognitionMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenrecognitionMenuSelected, true);
            app.OpenrecognitionMenu.Text = 'Open recognition';
            app.SaverecognitionMenu = uimenu(app.FileMenu);
            app.SaverecognitionMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCurrentLocationsSPACEButtonPushed, true);
            app.SaverecognitionMenu.Text = 'Save recognition';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.ImagePropertiesPanel = uipanel(app.ConeFinderUIFigure);
            app.ImagePropertiesPanel.Title = 'Image Properties';
            app.BrightnessPanel = uipanel(app.ImagePropertiesPanel);
            app.BrightnessPanel.Title = 'Brightness';
            app.BrightnessDBSliderLabel = uilabel(app.BrightnessPanel);
            app.BrightnessDBSliderLabel.Text = 'Brightness [D, B]';
            app.BrightnessDBSlider = uislider(app.BrightnessPanel);
            app.BrightnessDBSlider.Limits = [0 2];
            app.BrightnessDBSlider.MajorTicks = [0 0.5 1 1.5 2];
            app.BrightnessDBSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessDBSliderValueChanging, true);
            app.BrightnessDBSlider.Value = 1;
            app.ResetBrightnessRButton = uibutton(app.BrightnessPanel, 'push');
            app.ResetBrightnessRButton.ButtonPushedFcn = createCallbackFcn(app, @ResetBrightnessRButtonValueChanged, true);
            app.ResetBrightnessRButton.Text = 'Reset Brightness [R]';
            app.GridPanel = uipanel(app.ImagePropertiesPanel);
            app.GridPanel.Title = 'Grid';
            app.ShowGridQCheckBox = uicheckbox(app.GridPanel);
            app.ShowGridQCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGridQCheckBoxValueChanged, true);
            app.ShowGridQCheckBox.Text = 'Show Grid [Q]';
            app.ShowGridNumbersECheckBox = uicheckbox(app.GridPanel);
            app.ShowGridNumbersECheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGirdNumbersECheckBoxValueChanged, true);
            app.ShowGridNumbersECheckBox.Text = 'Show Grid Numbers [E]';
            app.LogfilterLCheckBox = uicheckbox(app.ImagePropertiesPanel);
            app.LogfilterLCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogImageLCheckBoxValueChanged, true);
            app.LogfilterLCheckBox.Text = 'Log filter [L]';
            app.LaplacianofGaussianfilterCheckBox = uicheckbox(app.ImagePropertiesPanel);
            app.LaplacianofGaussianfilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @LapOfGausCheckBoxValueChanged, true);
            app.LaplacianofGaussianfilterCheckBox.Text = 'Laplacian of Gaussian filter';
            app.ShowImageICheckBox = uicheckbox(app.ImagePropertiesPanel);
            app.ShowImageICheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowimageICheckBoxValueChanged, true);
            app.ShowImageICheckBox.Text = 'ShowImage [I]';
            app.ShowImageICheckBox.Value = true;
            app.MarksPropertiesPanel = uipanel(app.ConeFinderUIFigure);
            app.MarksPropertiesPanel.Title = 'Marks Properties';
            app.ConeMarksTypeButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ConeMarksTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConeMarksTypeButtonGroupSelectionChanged, true);
            app.ConeMarksTypeButtonGroup.Title = 'Cone Marks Type';
            app.DotZButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.DotZButton.Text = 'Dot [Z]';
            app.DotZButton.Value = true;
            app.CircleCButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CircleCButton.Text = 'Circle [C]';
            app.CrossXButton = uiradiobutton(app.ConeMarksTypeButtonGroup);
            app.CrossXButton.Text = 'Cross [X]';
            app.ShowMarksOptionsButtonGroup = uibuttongroup(app.MarksPropertiesPanel);
            app.ShowMarksOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ShowMarksOptionsButtonGroupSelectionChanged, true);
            app.ShowMarksOptionsButtonGroup.Title = 'Show Marks Options';
            app.AutoUser3Button = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.AutoUser3Button.Text = 'Auto + User [3]';
            app.AutoUser3Button.Value = true;
            app.Userdetect2Button = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.Userdetect2Button.Text = 'Userdetect [2]';
            app.Autodetect1Button = uiradiobutton(app.ShowMarksOptionsButtonGroup);
            app.Autodetect1Button.Text = 'Autodetect [1]';
            app.HighlightAutodetectedCones0CheckBox = uicheckbox(app.MarksPropertiesPanel);
            app.HighlightAutodetectedCones0CheckBox.ValueChangedFcn = createCallbackFcn(app, @HighlightAutodetectedCones0CheckBoxValueChanged, true);
            app.HighlightAutodetectedCones0CheckBox.Text = 'Highlight Autodetected Cones [0]';
            app.ShowMarkedLocationsTCheckBox = uicheckbox(app.MarksPropertiesPanel);
            app.ShowMarkedLocationsTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowMarkedLocationsTSwitchValueChanged, true);
            app.ShowMarkedLocationsTCheckBox.Text = 'Show Marked Locations [T]';
            app.ShowMarkedLocationsTCheckBox.Value = true;
            app.EditConeLocationsPanel = uipanel(app.ConeFinderUIFigure);
            app.EditConeLocationsPanel.Title = 'Edit Cone Locations';
            app.ChangeBoxSizeButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.ChangeBoxSizeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeBoxSizeButtonPushed, true);
            app.ChangeBoxSizeButton.Text = 'Change Box Size';
            app.DeletedSelectedConesDelButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.DeletedSelectedConesDelButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedSelectedConesDelButtonPushed, true);
            app.DeletedSelectedConesDelButton.Text = 'Deleted Selected Cones [Del]';
            app.AddConelocsbyfastpeakfindButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.AddConelocsbyfastpeakfindButton.ButtonPushedFcn = createCallbackFcn(app, @AddConelocsbyfastpeakfindButtonPushed, true);
            app.AddConelocsbyfastpeakfindButton.Text = 'Add Conelocs by fast peak find';
            app.AddConelocsOutsideBoxCNNButton = uibutton(app.EditConeLocationsPanel, 'push');
            app.AddConelocsOutsideBoxCNNButton.ButtonPushedFcn = createCallbackFcn(app, @AddConelocsOutsideBoxCNNButtonPushed, true);
            app.AddConelocsOutsideBoxCNNButton.Text = 'Add Conelocs Outside Box (CNN)';
            app.HelpTextAreaLabel = uilabel(app.EditConeLocationsPanel);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.EditConeLocationsPanel);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'HOTKEYS WORKS ONLY IN EDIT MODE!!!!!'; ''; 'Mouse Left Click - place Mark'; 'Mouse Right Click - remove Mark'; '''+'' - zoom in'; '''-'' - zoom out'; '↓ ↑  ← → - pan image'; 'Esc - turn off Edit Mode'};
            app.VoronoiPanel = uipanel(app.ConeFinderUIFigure);
            app.VoronoiPanel.Title = 'Voronoi';
            app.ShowConeLocationsCheckBox = uicheckbox(app.VoronoiPanel);
            app.ShowConeLocationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowConeLocationsCheckBoxValueChanged, true);
            app.ShowConeLocationsCheckBox.Text = 'Show Cone Locations';
            app.ImproveCNNResultsPanel = uipanel(app.ConeFinderUIFigure);
            app.ImproveCNNResultsPanel.Title = 'Improve CNN Results';
            app.DelOutsideImageButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.DelOutsideImageButton.ButtonPushedFcn = createCallbackFcn(app, @DelOutsideImageButtonPushed, true);
            app.DelOutsideImageButton.Text = 'Del Outside Image';
            app.DelDoublesButton = uibutton(app.ImproveCNNResultsPanel, 'push');
            app.DelDoublesButton.ButtonPushedFcn = createCallbackFcn(app, @DelDoublesButtonPushed, true);
            app.DelDoublesButton.Text = 'Del Doubles';
            app.InfoLabel = uilabel(app.ConeFinderUIFigure);
            app.InfoLabel.Text = {'Info'; ''};
            app.VoronoitypeButtonGroup = uibuttongroup(app.ConeFinderUIFigure);
            app.VoronoitypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VoronoitypeButtonGroupSelectionChanged, true);
            app.VoronoitypeButtonGroup.Title = 'Voronoi type';
            app.EmptyButton = uiradiobutton(app.VoronoitypeButtonGroup);
            app.EmptyButton.Tag = 'Empty';
            app.EmptyButton.Text = 'Empty';
            app.EmptyButton.Value = true;
            app.ConeAreaButton = uiradiobutton(app.VoronoitypeButtonGroup);
            app.ConeAreaButton.Tag = 'Cone Area';
            app.ConeAreaButton.Text = 'Cone Area';
            app.NumberofNeighborsButton = uiradiobutton(app.VoronoitypeButtonGroup);
            app.NumberofNeighborsButton.Tag = 'Number of Neighbors';
            app.NumberofNeighborsButton.Text = 'Number of Neighbors';
            app.VoronoiDiagramCheckBox = uicheckbox(app.VoronoitypeButtonGroup);
            app.VoronoiDiagramCheckBox.ValueChangedFcn = createCallbackFcn(app, @VoronoiDiagramCheckBoxValueChanged, true);
            app.VoronoiDiagramCheckBox.Text = 'Voronoi Diagram';
            % Show the figure after all components are created
            app.ConeFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConeFinderApp_v2020b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConeFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeFinderUIFigure)
        end
    end
end