classdef DensityContourSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CancelButton                   matlab.ui.control.Button
        OKButton                       matlab.ui.control.Button
        MetrictouseButtonGroup         matlab.ui.container.ButtonGroup
        YellotsRingsButton             matlab.ui.control.RadioButton
        NNDmeanButton                  matlab.ui.control.RadioButton
        EuclidianNConesButton          matlab.ui.control.RadioButton
        ShowparametersPanel            matlab.ui.container.Panel
        MainlinespercentagedifferenceEditField  matlab.ui.control.NumericEditField
        MainlinespercentagedifferenceEditFieldLabel  matlab.ui.control.Label
        SinglelinepercentageEditField  matlab.ui.control.NumericEditField
        SinglelinepercentageEditFieldLabel  matlab.ui.control.Label
        ShowpercenttextCheckBox        matlab.ui.control.CheckBox
        ShowmainpercentagelinesCheckBox  matlab.ui.control.CheckBox
        ShowsinglepercentagelineCheckBox  matlab.ui.control.CheckBox
        ColorsPanel                    matlab.ui.container.Panel
        PickSingleLineColorButton      matlab.ui.control.Button
        PickMainColorButton            matlab.ui.control.Button
        SingleLineColorPreviewAxes     matlab.ui.control.UIAxes
        MainColorPreviewAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainAppHandler = [];
        MainColor = [0 0 1];
        SingleLineColor = [0 0 1];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainAppHandler = mainapp;
%             app.AutosaveCheckBox.Value = app.MainAppHandler.IsAutosaveOn;
%             app.StartFolderEditField.Value = app.MainAppHandler.StartFolder;
        end

        % Button pushed function: PickMainColorButton
        function PickMainColorButtonPushed(app, event)
            color = uisetcolor();
            previewColor = zeros(50, 50, 3);
            previewColor(:, :, 1) = color(1);
            previewColor(:, :, 2) = color(2);
            previewColor(:, :, 3) = color(3);
            imshow(previewColor, 'Parent',  app.MainColorPreviewAxes);
            app.MainColor = color;
        end

        % Button pushed function: PickSingleLineColorButton
        function PickSingleLineColorButtonPushed(app, event)
            color = uisetcolor();
            previewColor = zeros(50, 50, 3);
            previewColor(:, :, 1) = color(1);
            previewColor(:, :, 2) = color(2);
            previewColor(:, :, 3) = color(3);
            imshow(previewColor, 'Parent',  app.SingleLineColorPreviewAxes);
            app.SingleLineColor = color;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
%             app.MainAppHandler.StartFolder = app.StartFolderEditField.Value;
%             app.MainAppHandler.IsAutosaveOn = app.AutosaveCheckBox.Value;
%             app.MainAppHandler.UpdateParameters();
            
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorsPanel = uipanel(app.UIFigure);
            app.ColorsPanel.Title = 'Colors';
            app.MainColorPreviewAxes = uiaxes(app.ColorsPanel);
            app.MainColorPreviewAxes.XTick = [];
            app.MainColorPreviewAxes.YTick = [];
            app.SingleLineColorPreviewAxes = uiaxes(app.ColorsPanel);
            app.SingleLineColorPreviewAxes.XTick = [];
            app.SingleLineColorPreviewAxes.YTick = [];
            app.PickMainColorButton = uibutton(app.ColorsPanel, 'push');
            app.PickMainColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickMainColorButtonPushed, true);
            app.PickMainColorButton.Text = 'Pick Main Color';
            app.PickSingleLineColorButton = uibutton(app.ColorsPanel, 'push');
            app.PickSingleLineColorButton.ButtonPushedFcn = createCallbackFcn(app, @PickSingleLineColorButtonPushed, true);
            app.PickSingleLineColorButton.Text = 'Pick Single Line Color';
            app.ShowparametersPanel = uipanel(app.UIFigure);
            app.ShowparametersPanel.Title = 'Show parameters';
            app.ShowsinglepercentagelineCheckBox = uicheckbox(app.ShowparametersPanel);
            app.ShowsinglepercentagelineCheckBox.Text = 'Show single percentage line';
            app.ShowmainpercentagelinesCheckBox = uicheckbox(app.ShowparametersPanel);
            app.ShowmainpercentagelinesCheckBox.Text = 'Show main percentage lines';
            app.ShowmainpercentagelinesCheckBox.Value = true;
            app.ShowpercenttextCheckBox = uicheckbox(app.ShowparametersPanel);
            app.ShowpercenttextCheckBox.Text = 'Show percent text';
            app.SinglelinepercentageEditFieldLabel = uilabel(app.ShowparametersPanel);
            app.SinglelinepercentageEditFieldLabel.Text = 'Single line percentage';
            app.SinglelinepercentageEditField = uieditfield(app.ShowparametersPanel, 'numeric');
            app.SinglelinepercentageEditField.Limits = [0 1];
            app.SinglelinepercentageEditField.Value = 0.2;
            app.MainlinespercentagedifferenceEditFieldLabel = uilabel(app.ShowparametersPanel);
            app.MainlinespercentagedifferenceEditFieldLabel.Text = 'Main lines percentage difference';
            app.MainlinespercentagedifferenceEditField = uieditfield(app.ShowparametersPanel, 'numeric');
            app.MainlinespercentagedifferenceEditField.Limits = [0 1];
            app.MainlinespercentagedifferenceEditField.Value = 0.1;
            app.MetrictouseButtonGroup = uibuttongroup(app.UIFigure);
            app.MetrictouseButtonGroup.Title = 'Metric to use';
            app.EuclidianNConesButton = uiradiobutton(app.MetrictouseButtonGroup);
            app.EuclidianNConesButton.Tag = 'euclidnc';
            app.EuclidianNConesButton.Text = 'EuclidianNCones';
            app.EuclidianNConesButton.Value = true;
            app.NNDmeanButton = uiradiobutton(app.MetrictouseButtonGroup);
            app.NNDmeanButton.Tag = 'nnd_mean';
            app.NNDmeanButton.Text = 'NNDmean';
            app.YellotsRingsButton = uiradiobutton(app.MetrictouseButtonGroup);
            app.YellotsRingsButton.Tag = 'yellots_rings';
            app.YellotsRingsButton.Text = 'YellotsRings';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DensityContourSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end