classdef Settings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AutosaveCheckBox           matlab.ui.control.CheckBox
        OKButton                   matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        StartFolderEditField       matlab.ui.control.EditField
        StartFolderEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainAppHandler = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainAppHandler = mainapp;
            app.AutosaveCheckBox.Value = app.MainAppHandler.IsAutosaveOn;
            app.StartFolderEditField.Value = app.MainAppHandler.StartFolder;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            folder = uigetdir(pwd, 'Select start folder...');
            app.StartFolderEditField.Value = folder;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainAppHandler.StartFolder = app.StartFolderEditField.Value;
            app.MainAppHandler.IsAutosaveOn = app.AutosaveCheckBox.Value;
            app.MainAppHandler.UpdateParameters();
            
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartFolderEditFieldLabel = uilabel(app.UIFigure);
            app.StartFolderEditFieldLabel.Text = 'Start Folder';
            app.StartFolderEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.AutosaveCheckBox = uicheckbox(app.UIFigure);
            app.AutosaveCheckBox.Text = 'Autosave';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Settings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end