classdef StatisticsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConeFinderStatisticsUIFigure  matlab.ui.Figure
        StatisticsPanel               matlab.ui.container.Panel
        CDCDensValVLabel              matlab.ui.control.Label
        CDCdencityvalueLabel          matlab.ui.control.Label
        PCDDensValVLabel              matlab.ui.control.Label
        PCDdensityvalueLabel          matlab.ui.control.Label
        PCDcoordsVLabel               matlab.ui.control.Label
        PCDcoordinatesLabel           matlab.ui.control.Label
        CDCcoordsVLabel               matlab.ui.control.Label
        CDCcoordinatesLabel           matlab.ui.control.Label
        BrightnessVLabel              matlab.ui.control.Label
        DensRadVLabel                 matlab.ui.control.Label
        DensVLabel                    matlab.ui.control.Label
        XYCoordVLabel                 matlab.ui.control.Label
        XYcoordinatesLabel            matlab.ui.control.Label
        BrightnessvalueLabel          matlab.ui.control.Label
        DensityradialvalueLabel       matlab.ui.control.Label
        DensityvalueLabel             matlab.ui.control.Label
        NumOfConesVLabel              matlab.ui.control.Label
        NumberofconesLabel            matlab.ui.control.Label
        radialAvgUIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainAppHandler           matlab.apps.AppBase;
        DensityHandler = [];

        DensityRadialAverage = [];
        Radiuses = [];
        IsApproximated = [];
        CurrentPointPlotHandler = [];
    end
    
    methods (Access = public)
        
        % Updates Data, which depends on current coordinates:
        % current coordinates, brightness, density value
        function UpdateDynamicData(app, x, y)
            [height, width] = size(app.MainAppHandler.Image);
            indexY = round(y);
            indexX = round(x);
            if indexX > 0 && indexX < width && indexY > 0 && indexY < height
                app.XYCoordVLabel.Text = sprintf('X: %8.2f; Y: %8.2f', x, y);
                app.BrightnessVLabel.Text = num2str(app.MainAppHandler.Image(indexY, indexX));

                if ~isempty(app.DensityHandler) && isvalid(app.DensityHandler)
                    app.DensVLabel.Text = sprintf('%8.6f', app.DensityHandler.DensityMatrix(indexY, indexX));
                    distance = round(pdist2([indexX, indexY], round(app.DensityHandler.CDC20_loc)));
                    radIndex = find(app.Radiuses == distance, 1, 'first');
                    app.CurrentPointPlotHandler.XData = app.Radiuses(radIndex);
                    app.CurrentPointPlotHandler.YData = app.DensityRadialAverage(radIndex);
                    app.DensRadVLabel.Text = sprintf('%8.6f', app.DensityRadialAverage(radIndex));
                end
            else

            end
        end

        % Updates Data, which not depends on current coordinates:
        % number of cones, CDC, PCD
        function UpdateStaticData(app)
            if ~isempty(app.DensityHandler) && isvalid(app.DensityHandler)
                app.CDCcoordsVLabel.Text = sprintf('X: %8.2f; Y: %8.2f', app.DensityHandler.CDC20_loc(1), app.DensityHandler.CDC20_loc(2));
                app.CDCDensValVLabel.Text = sprintf('%8.6f', app.DensityHandler.DensityMatrix(round(app.DensityHandler.CDC20_loc(2)), round(app.DensityHandler.CDC20_loc(1))));
                app.PCDcoordsVLabel.Text = sprintf('X: %8.2f; Y: %8.2f', app.DensityHandler.PCD_loc(1), app.DensityHandler.PCD_loc(2));
                app.PCDDensValVLabel.Text = sprintf('%8.6f', app.DensityHandler.DensityMatrix(round(app.DensityHandler.PCD_loc(2)), round(app.DensityHandler.PCD_loc(1))));
            end

            app.NumOfConesVLabel.Text = num2str(app.MainAppHandler.TotalConeCount);

            PlotRadialAverage(app);
        end

        function RefreshAll(app)
            app.DensityHandler = app.MainAppHandler.GetDensityHandlerFromRadioButtons();
            UpdateStaticData(app);
            app.XYCoordVLabel.Text = "-";
            app.BrightnessVLabel.Text = "-";
            app.DensVLabel.Text = "-";
            app.DensRadVLabel.Text = "-";
        end
    end
    
    methods (Access = private)
        
        function PlotRadialAverage(app)
            if ~isempty(app.DensityHandler) && isvalid(app.DensityHandler)
                [app.DensityRadialAverage, app.Radiuses, app.IsApproximated] = GetDensityRadialAverage(app.DensityHandler.DensityMatrix, app.DensityHandler.CDC20_loc);
    
                plot(app.radialAvgUIAxes, app.Radiuses, app.DensityRadialAverage, '-b');
                indexOfApproximate = find(app.IsApproximated, 1, 'first');
                xline(app.radialAvgUIAxes, app.Radiuses(indexOfApproximate));
                hold(app.radialAvgUIAxes, "on");
                app.CurrentPointPlotHandler = plot(app.radialAvgUIAxes, app.Radiuses(1), app.DensityRadialAverage(1), ...
                    'Marker', 'x', ...
                    'MarkerEdgeColor', [201/255 60/255 56/255], ...
                    'MarkerSize', 6, ...
                    'LineWidth', 2,...
                    'LineStyle', 'none');
                hold(app.radialAvgUIAxes, "off");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainAppHandler)
            app.MainAppHandler = mainAppHandler;
            app.DensityHandler = mainAppHandler.GetDensityHandlerFromRadioButtons();
            RefreshAll(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConeFinderStatisticsUIFigure = uifigure('Visible', 'off');
            app.ConeFinderStatisticsUIFigure.Name = 'Cone Finder Statistics';
            app.radialAvgUIAxes = uiaxes(app.ConeFinderStatisticsUIFigure);
            title(app.radialAvgUIAxes, 'Density Radial Average')
            xlabel(app.radialAvgUIAxes, 'Distance from CDC')
            ylabel(app.radialAvgUIAxes, 'Average Density')
            zlabel(app.radialAvgUIAxes, 'Z')
            app.StatisticsPanel = uipanel(app.ConeFinderStatisticsUIFigure);
            app.StatisticsPanel.Title = 'Statistics';
            app.NumberofconesLabel = uilabel(app.StatisticsPanel);
            app.NumberofconesLabel.Text = 'Number of cones';
            app.NumOfConesVLabel = uilabel(app.StatisticsPanel);
            app.DensityvalueLabel = uilabel(app.StatisticsPanel);
            app.DensityvalueLabel.Text = 'Density value';
            app.DensityradialvalueLabel = uilabel(app.StatisticsPanel);
            app.DensityradialvalueLabel.Text = 'Density radial value';
            app.BrightnessvalueLabel = uilabel(app.StatisticsPanel);
            app.BrightnessvalueLabel.Text = 'Brightness value';
            app.XYcoordinatesLabel = uilabel(app.StatisticsPanel);
            app.XYcoordinatesLabel.Text = 'X, Y coordinates';
            app.XYCoordVLabel = uilabel(app.StatisticsPanel);
            app.DensVLabel = uilabel(app.StatisticsPanel);
            app.DensRadVLabel = uilabel(app.StatisticsPanel);
            app.BrightnessVLabel = uilabel(app.StatisticsPanel);
            app.CDCcoordinatesLabel = uilabel(app.StatisticsPanel);
            app.CDCcoordinatesLabel.Text = 'CDC coordinates';
            app.CDCcoordsVLabel = uilabel(app.StatisticsPanel);
            app.PCDcoordinatesLabel = uilabel(app.StatisticsPanel);
            app.PCDcoordinatesLabel.Text = 'PCD coordinates';
            app.PCDcoordsVLabel = uilabel(app.StatisticsPanel);
            app.PCDdensityvalueLabel = uilabel(app.StatisticsPanel);
            app.PCDdensityvalueLabel.Text = 'PCD density value';
            app.PCDDensValVLabel = uilabel(app.StatisticsPanel);
            app.CDCdencityvalueLabel = uilabel(app.StatisticsPanel);
            app.CDCdencityvalueLabel.Text = 'CDC dencity value';
            app.CDCDensValVLabel = uilabel(app.StatisticsPanel);
            % Show the figure after all components are created
            app.ConeFinderStatisticsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatisticsWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ConeFinderStatisticsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ConeFinderStatisticsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConeFinderStatisticsUIFigure)
        end
    end
end