classdef TestApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartConeFinderButton  matlab.ui.control.StateButton
        VoronoiiButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        Map; % Description
        Conelocs = [];
        Voronoiplot = 1;
        I;
        Marker_Size = 2;
        Boxposition;
    end
    

    methods (Access = private)

        % Value changed function: StartConeFinderButton
        function StartConeFinderButtonValueChanged(app, event)
            format shortg
            gamma = 1.0;
            log_image = 1;
            markson = 1;
            imageon = 1;
            bothcones = 1;
            %voronoiplot = 1;
            conelocs = [];
            % Colormap for Voronoi patches
            % map = colormap('colorcube'); close(gcf);
            % numcolors = 15;  % ********* change from 15
            % idx = round(linspace(1,15,numcolors));
            % neighcolors = map(idx,:);
            % neighcolors = flipud(neighcolors);
            
            colormaps_mpl2019;
            % map = colormap(inferno); close(gcf);
            app.Map = colormap(viridis); close(gcf);
            % adjust settings for colormap in VoronoiPatch function ~ line 660
            
            %% User input - Select files to analyse
            % Select tiff to analyze, or mat file in case revisiting already counted tiff
            
            start_path = ([cd filesep]);
            % start_path = (['C:\Users\Jenny\Documents\Projekte\foveal_structure_and_function\Probandendaten_STUDIE\foveal9_mosaics\MARKED_files (g1+cunefare CNN Training)' filesep]);
            % start_path = (['E:\ausgelagerte_Studiendaten\788_gradedImages' filesep]);
            
            % file selection GUI
            [fnames, pname] = uigetfile('*.mat;*.tiff;*.tif;', 'Select file(s) to mark', start_path, 'MultiSelect', 'on');
            
            % checks if user cancelled
            if isequal(fnames,0) || isequal(pname,0)
                % display a message in the commmand window
                disp(' ');disp(' ');disp(' ');disp(' ----> User cancelled script');disp(' ');disp(' ');
                % if cancelled exit script
                return
            end
            
            % determine number of selected filess
            sumnorms = cellstr(fnames);
            nfiles = size(fnames(:),1);
            
            if nfiles > 10
                nfiles = 1;
            end
            
            if nfiles > 1
                multiple_mosaics = 1;
                fname = fnames{1};
                [pstr, nm, ext] = fileparts([pname fname]);
            else
                multiple_mosaics = 0;
                fname = fnames;
                [pstr, nm, ext] = fileparts([pname fname]);
            end
            
            %% In case of Multiselect - prepare for changing the mosaic image
            if multiple_mosaics
                
                ref_image=fnames{1};
                refframe = imread([pname ref_image]);
                
                pad = 100;
                
                %     rect1 = [206,206,301,301];
                %     rect2 = [306,306,201,201];
                rect1 = [200,200,201,201];
                rect2 = [312,200,201,201];
                rect3 = [200,312,201,201];
                rect4 = [312,312,201,201];
                %     rect5 = [308,164,79,183];
                
                [fheight, fwidth] = size(refframe);
                % rect is [start x coordinate, y coordinate, width, height]
                refcrop1 = imcrop(refframe,rect1);
                refcrop2 = imcrop(refframe,rect2);
                refcrop3 = imcrop(refframe,rect3);
                refcrop4 = imcrop(refframe,rect4);
                %     refcrop5 = imcrop(refframe,rect5);
                
                %     figure, imshow(refframe)
                %     title('Reference Frame')
                
                sumframe=zeros(fheight+(pad*2),fwidth+(pad*2));
                sumframebinary=ones(fheight+(pad*2),fwidth+(pad*2));
                
                for nf = 1:nfiles
                    frame = imread([pname fnames{nf}]);
                    % rect is [start x coordinate, y coordinate, width, height]
                    crop1 = imcrop(frame,rect1);
                    crop2 = imcrop(frame,rect2);
                    crop3 = imcrop(frame,rect3);
                    crop4 = imcrop(frame,rect4);
                    %         crop5 = imcrop(frame,rect5);
                    
                    [shift1, dump] = dftregistration(fft2(refcrop1),fft2(crop1),10);
                    [shift2, dump] = dftregistration(fft2(refcrop2),fft2(crop2),10);
                    [shift3, dump] = dftregistration(fft2(refcrop3),fft2(crop3),10);
                    [shift4, dump] = dftregistration(fft2(refcrop4),fft2(crop4),10);
                    %         [shift5, dump] = dftregistration(fft2(refcrop5),fft2(crop5),10);
                    
                    
                    shifts(:,:,nf) = [shift1(3:4);shift2(3:4);shift3(3:4);shift4(3:4);];
                    %         shifts(:,:,nf) = [shift1(3:4);shift2(3:4);];
                    meanshift(nf,:) = mean(shifts(:,:,nf));
                    deviation(nf) = mean(std(shifts(:,:,nf)));
                    
                    f = double(zeros(fheight+(pad*2),fwidth+(pad*2)));
                    f(pad+1:(fheight+pad),pad+1:(fwidth+pad))=frame;
                    
                    deltar = -meanshift(nf,1);
                    deltac = -meanshift(nf,2);
                    phase = 2;
                    [nr,nc]=size(f);
                    Nr = ifftshift((-fix(nr/2):ceil(nr/2)-1));
                    Nc = ifftshift((-fix(nc/2):ceil(nc/2)-1));
                    [Nc,Nr] = meshgrid(Nc,Nr);
                    g = abs(ifft2(fft2(f).*exp(1i*2*pi*(deltar*Nr/nr+deltac*Nc/nc))).*exp(-1i*phase));
                    g = g./max(g(:));
                    
                    change_mosaic{nf} = g(101:812,101:812);
                    
                    %         figure, imshow(g)
                    %         title(['Frame ', num2str(nf)])
                    
                    clear f deltar deltac Nc Nr nc nr g
                end
                
                %     figure, imshow(change_mosaic(:,:,1));
                %     imwrite(change_mosaic(:,:,1),'frame1.tif','tif','Compression','none');
                %     figure, imshow(change_mosaic(:,:,2));
                %     imwrite(change_mosaic(:,:,2),'frame2.tif','tif','Compression','none');
                %     figure, imshow(change_mosaic(:,:,3));
                %     imwrite(change_mosaic(:,:,3),'frame3.tif','tif','Compression','none');
                
            end
            
            %% Prepare file to start/continue cone marking
            if strcmp(ext,'.tiff')==1 || strcmp(ext,'.tif')==1
                
                if multiple_mosaics
                    mosaic = 1;
                    app.I = change_mosaic{mosaic};
                elseif nfiles == 1
                    app.I = imread([pname fname]);
                    change_mosaic{1} = app.I;
                end
                
                h.fig = figure;
                imshow(app.I), hold on
                
                box_size = questdlg('Select box size', 'Define window size for analysis', '200x200 pixel', ...
                    '512x512  pixel', '1024x1024  pixel', '1024x1024  pixel');
                if strcmp(box_size, '200x200 pixel')
                    hi = imrect(gca,[0 0 200 200]);
                    app.Marker_Size = 2;
                elseif strcmp(box_size, '512x512  pixel')
                    hi = imrect(gca,[0 0 512 512]);
                    app.Marker_Size = 2;
                elseif strcmp(box_size, '1024x1024  pixel')
                    hi = imrect(gca,[0 0 1024 1024]);
                    app.Marker_Size = 2;
                end
                
                app.Boxposition = wait(hi);
                %     app.Boxposition = [295.8507 239.8978 200 200];    % defined, preselected positions for Box can be entered here, before starting the script
                %     app.Boxposition = [107.2877 97.5342 512 512];    % defined, preselected positions for Box can be entered here, before starting the script
                minlimx = app.Boxposition(1);
                minlimy = app.Boxposition(2);
                maxlimx = minlimx + app.Boxposition(3);
                maxlimy = minlimy + app.Boxposition(4);
                
                cla; a1 = imshow(app.I);title(fname,'Interpreter','none'), hold on;
                ax = gca; ttl = ax.Title; ttl.FontSize = 14;
                ttl.BackgroundColor = [1 0 0];
                rectangle('Position',app.Boxposition,'EdgeColor',[1 0 1]);
                
                % QUESTDLG for use of the FastPeakFinder
                ButtonName = questdlg(' Use automatic cone finding algorithm? ', ...
                    'Startup condition', ...
                    'Fast Peak Finder', 'CNN','No','No');
                switch ButtonName
                    case 'Fast Peak Finder'
                        p = FastPeakFind(app.I);
                        conelocs(:,1) = p(1:2:end);
                        conelocs(:,2) = p(2:2:end);
                        conelocs(:,3) = 1;
                        
                        conelocs(conelocs(:,1)<=minlimx,3)= 0;
                        conelocs(conelocs(:,1)>=maxlimx,3)= 0;
                        conelocs(conelocs(:,2)<=minlimy,3)= 0;
                        conelocs(conelocs(:,2)>=maxlimy,3)= 0;
                        
                        conelocs = unique(conelocs, 'rows', 'stable');
                        
                        plot(conelocs(conelocs(:,3)~=0,1),conelocs(conelocs(:,3)~=0,2),'.y', 'MarkerSize', app.Marker_Size)
                        conecount = size(conelocs(conelocs(:,3)~=0,1),1);
                        totalconecount = size(conelocs,1);
                        
                    case 'CNN'
                        p = FastPeakFind(app.I);
                        
                        %             DataSet = '14111';  % dataSet specifies trained CNN (grader 1 trained network - 14(x16) training images and 1(x16) validation image)
                        DataSet = 'g1+cunefare';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
                        %             DataSet = '42';  % dataSet specifies trained CNN (grader 1 14(x16)training + 100 cunefare val images and 1(x16) validation g1 + 16 cunefare training images)
                        
                        ImageDir = [pname fname];
                        tic;
                        startTimeCNN = clock;
                        fprintf('startTimeCNN: %s \n', sprintf('%g ', startTimeCNN));
                        [conelocs] = RunCNNnewSet_automatic(DataSet, ImageDir, app.Boxposition, app.I);
                        endTimeCNN = clock;
                        fprintf('endTimeCNN: %s \n', sprintf('%g ', endTimeCNN));
                        toc;
                        disp(' ');
                        disp(' ');
                        
                        conelocs = unique(conelocs,'rows', 'stable');
                        
                        %             conelocs(:,1) = p(1:2:end);
                        %             conelocs(:,2) = p(2:2:end);
                        conelocs(:,3) = 1;
                        
                        conelocs(conelocs(:,1)<=minlimx,3)= 0;
                        conelocs(conelocs(:,1)>=maxlimx,3)= 0;
                        conelocs(conelocs(:,2)<=minlimy,3)= 0;
                        conelocs(conelocs(:,2)>=maxlimy,3)= 0;
                        
                        conelocs = unique(conelocs, 'rows', 'stable');
                        
                        plot(conelocs(conelocs(:,3)~=0,1),conelocs(conelocs(:,3)~=0,2),'.y', 'MarkerSize', app.Marker_Size)
                        conecount = size(conelocs(conelocs(:,3)~=0,1),1);
                        totalconecount = size(conelocs,1);
                        
                        
                    case 'No'
                        conecount = 0;
                end % switch
                title([fname,'  ',num2str(conecount),' cones'],'Interpreter','none')
                
                if multiple_mosaics == 1
                    change_mosaic{1} = app.I;
                    mosaic = 1;
                    %         app.I = app.I(:,:,mosaic);
                else
                    change_mosaic{1} = app.I;
                    mosaic = 1;
                end
                
            elseif strcmp(ext,'.mat')==1
                load([pname fname]);
                conelocs = unique(conelocs, 'rows', 'stable');
                conecount = size(conelocs(conelocs(:,3)~=0,1),1);
                totalconecount = size(conelocs,1);
                
                minlimx = app.Boxposition(1);
                minlimy = app.Boxposition(2);
                maxlimx = minlimx + app.Boxposition(3);
                maxlimy = minlimy + app.Boxposition(4);
                
                if multiple_mosaics == 1
                    change_mosaic = app.I;
                    mosaic = 1;
                    app.I = change_mosaic{1};
                else
                    change_mosaic = app.I;
                    mosaic = 1;
                end
                
                app.Marker_Size = 4;
                
                h.fig = figure; imshow(app.I), title([fname,'  ',num2str(conecount),' cones'],'Interpreter','none'), hold on
                ax = gca;                    % get the current axis
                ttl = ax.Title;              % get the title text object
                ttl.FontSize = 14;
                ttl.BackgroundColor = [1 0 0];
                
                cla; a1 = imshow(app.I);title([fname,'  ',num2str(conecount),' cones'],'Interpreter','none'), hold on;
                rectangle('Position',app.Boxposition,'EdgeColor',[1 0 1]);
                
                
                plot(conelocs(conelocs(:,3)~=0,1),conelocs(conelocs(:,3)~=0,2),'.y', 'MarkerSize', app.Marker_Size)
            end
            
            app.Conelocs = conelocs;
        end

        % Button pushed function: VoronoiiButton
        function VoronoiiButtonPushed(app, event)
            
            conelocs = app.Conelocs;
            map = app.Map;
            
            numcolors = 25;  % ********* change from 15
            %         max_area_pixel = 150;        % basis for dividing cone Area through numcolors
            max_area_pixel = 80;        % basis for dividing cone Area through numcolors
            corr_factor = max_area_pixel/numcolors; % to represent whole colormap over values
            idx = round(linspace(1,256,numcolors));
            neighcolors = map(idx,:);
            neighcolors = flipud(neighcolors);
            
            min_neighbors = 4;
            max_neighbors = 9;
            numcolorsNN = max_neighbors-min_neighbors;  % ********* change from 15
            %         corr_factorNN = numcolorsNN/max_neighbours; % to represent whole colormap over values
            idxNN = round(linspace(1,256,numcolorsNN));
            neighcolorsNN = map(idxNN,:);
            neighcolorsNN = flipud(neighcolorsNN);
            
            if app.Voronoiplot
                cla; a1 = imshow(app.I); hold on;
                voronoi_type = questdlg('Select coloring for Voronoi patches', 'Coloring Voronois', 'Cone Area', ...
                    'Number of Neighbors', 'Cone Area');
                % Calculate num neighbours from Voronoi diagram
                if size(conelocs,1)>3
                    
                    vorocones = conelocs(conelocs(:,3)~=0,1:2);
                    vorocones = unique(vorocones, 'rows', 'stable');
                    
                    dt = delaunayTriangulation(vorocones(:,1),vorocones(:,2));
                    [V,C] = voronoiDiagram(dt);
                    [dump,G] = voronoin(vorocones(:,1:2));
                    for j = 1 : length ( G )
                        NumNeighbors(j,1) = size(G{j},2);
                        ConeArea(j,1) = polyarea(V(C{j},1),V(C{j},2));
                        idxMinMax = round(linspace(min(ConeArea),max_area_pixel,numcolors));
                    end
                end
                for j = 1:length(C)
                    if all(C{j}~=1)
                        
                        if strcmp(voronoi_type, 'Number of Neighbors')  % VARIANTE 1: Falschfarben sind Anzahl Nachbarn
                            if NumNeighbors(j)<= min_neighbors
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(1,:));
                            elseif NumNeighbors(j)> max_neighbors
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(end,:));
                            elseif NumNeighbors(j)> min_neighbors
                                %                             h_p = patch(V(C{j},1),V(C{j},2),neighcolors(NumNeighbors(j),:),'FaceAlpha',0.1);
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolorsNN(NumNeighbors(j)-min_neighbors,:));
                            end
                            
                        elseif strcmp(voronoi_type, 'Cone Area')        % VARIANTE 2: Falschfarben sind PatchgrцЯe
                            if ConeArea(j)<=max_area_pixel
                                [val,pos]=min(abs(idxMinMax-ConeArea(j)));
                                %                             h_p = patch(V(C{j},1),V(C{j},2),neighcolors(round(ConeArea(j)/corr_factor),:));
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolors(pos,:));
                                %                         if ConeArea(j)<=64
                                %                             h_p = patch(V(C{j},1),V(C{j},2),neighcolors(round(ConeArea(j)),:));
                            else
                                h_p = patch(V(C{j},1),V(C{j},2),neighcolors(end,:));
                            end
                        end                                             % VARIANTEN Ende
                    end
                end
                app.Voronoiplot = 0;
            else
                cla; a1 = imshow(app.I); hold on;
                plot(conelocs(conelocs(:,3)~=0,1),conelocs(conelocs(:,3)~=0,2),'.y', 'MarkerSize', app.Marker_Size)
                
                rectangle('Position',app.Boxposition,'EdgeColor',[1 0 1]);
                app.Voronoiplot = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.StartConeFinderButton = uibutton(app.UIFigure, 'state');
            app.StartConeFinderButton.ValueChangedFcn = createCallbackFcn(app, @StartConeFinderButtonValueChanged, true);
            app.StartConeFinderButton.Text = 'Start ConeFinder';
            app.VoronoiiButton = uibutton(app.UIFigure, 'push');
            app.VoronoiiButton.ButtonPushedFcn = createCallbackFcn(app, @VoronoiiButtonPushed, true);
            app.VoronoiiButton.Text = 'Voronoii';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TestApp1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end