classdef UnitsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UnitsUIFigure                   matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        PixelsperdergreeEditField       matlab.ui.control.NumericEditField
        PixelsperdergreeEditFieldLabel  matlab.ui.control.Label
        UnitsDropDown                   matlab.ui.control.DropDown
        UnitsDropDownLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainAppHandler = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainAppHandler = mainApp;
            app.PixelsperdergreeEditField.Value = app.MainAppHandler.PixelsPerDergree;
            app.UnitsDropDown.Value = app.MainAppHandler.Units;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.MainAppHandler.PixelsPerDergree = app.PixelsperdergreeEditField.Value;
            app.MainAppHandler.Units = app.UnitsDropDown.Value;

            if ~isempty(app.MainAppHandler.StatisticsWindowUIFigureHandler) && isvalid(app.MainAppHandler.StatisticsWindowUIFigureHandler)
                app.MainAppHandler.StatisticsWindowUIFigureHandler.RefreshAll();
            end
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UnitsUIFigure = uifigure('Visible', 'off');
            app.UnitsUIFigure.Name = 'Units';
            app.UnitsUIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'CF_icon.png');
            app.UnitsDropDownLabel = uilabel(app.UnitsUIFigure);
            app.UnitsDropDownLabel.Text = 'Units';
            app.UnitsDropDown = uidropdown(app.UnitsUIFigure);
            app.UnitsDropDown.Items = {'pixel', 'arcsec', 'arcmin', 'degree'};
            app.UnitsDropDown.Value = 'pixel';
            app.PixelsperdergreeEditFieldLabel = uilabel(app.UnitsUIFigure);
            app.PixelsperdergreeEditFieldLabel.Text = 'Pixels per dergree';
            app.PixelsperdergreeEditField = uieditfield(app.UnitsUIFigure, 'numeric');
            app.PixelsperdergreeEditField.Limits = [0 Inf];
            app.OKButton = uibutton(app.UnitsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.UnitsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UnitsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnitsWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UnitsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UnitsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UnitsUIFigure)
        end
    end
end